classdef new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FrequencyDomainECGAnalyzerUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        Panel                     matlab.ui.container.Panel
        GridLayout5               matlab.ui.container.GridLayout
        Panel_4                   matlab.ui.container.Panel
        GridLayout8               matlab.ui.container.GridLayout
        TextArea_Log              matlab.ui.control.TextArea
        Button_ClearLog           matlab.ui.control.Button
        Panel_3                   matlab.ui.container.Panel
        GridLayout21              matlab.ui.container.GridLayout
        Button_DelAll             matlab.ui.control.Button
        NoteLabel                 matlab.ui.control.Label
        GridLayout7               matlab.ui.container.GridLayout
        Button_PIH                matlab.ui.control.Button
        Button_Phase              matlab.ui.control.Button
        Button_RF                 matlab.ui.control.Button
        Button_CF                 matlab.ui.control.Button
        Button_Amplitude          matlab.ui.control.Button
        Button_AutoPower          matlab.ui.control.Button
        Panel_2                   matlab.ui.container.Panel
        GridLayout6               matlab.ui.container.GridLayout
        Button_DataPreprocessing  matlab.ui.control.Button
        Button_V5_Import          matlab.ui.control.Button
        Button_II_Import          matlab.ui.control.Button
        CenterPanel               matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        Panel_6                   matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        TabGroup                  matlab.ui.container.TabGroup
        Tab                       matlab.ui.container.Tab
        GridLayout9               matlab.ui.container.GridLayout
        TextArea_RawSigInfo       matlab.ui.control.TextArea
        GridLayout18              matlab.ui.container.GridLayout
        UIAxes_V5RawPic           matlab.ui.control.UIAxes
        UIAxes_IIRawPic           matlab.ui.control.UIAxes
        Tab_2                     matlab.ui.container.Tab
        GridLayout10              matlab.ui.container.GridLayout
        Panel_7                   matlab.ui.container.Panel
        GridLayout11              matlab.ui.container.GridLayout
        TULabel                   matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        NLabel                    matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        IILabel                   matlab.ui.control.Label
        V5Label                   matlab.ui.control.Label
        Lamp_8                    matlab.ui.control.Lamp
        Lamp_7                    matlab.ui.control.Lamp
        Lamp_6                    matlab.ui.control.Lamp
        Lamp_5                    matlab.ui.control.Lamp
        Lamp_4                    matlab.ui.control.Lamp
        Lamp_3                    matlab.ui.control.Lamp
        Lamp_2                    matlab.ui.control.Lamp
        Lamp_1                    matlab.ui.control.Lamp
        UIAxes_AutoPower          matlab.ui.control.UIAxes
        Tab_4                     matlab.ui.container.Tab
        GridLayout14              matlab.ui.container.GridLayout
        TextArea_AmpInfo          matlab.ui.control.TextArea
        UIAxes_AmpPic             matlab.ui.control.UIAxes
        Tab_3                     matlab.ui.container.Tab
        GridLayout12              matlab.ui.container.GridLayout
        GridLayout23              matlab.ui.container.GridLayout
        UIAxes_MTT                matlab.ui.control.UIAxes
        UIAxes_PhasePic           matlab.ui.control.UIAxes
        Panel_8                   matlab.ui.container.Panel
        GridLayout13              matlab.ui.container.GridLayout
        WLabel                    matlab.ui.control.Label
        DLabel                    matlab.ui.control.Label
        Lamp_10                   matlab.ui.control.Lamp
        Lamp_9                    matlab.ui.control.Lamp
        Tab_5                     matlab.ui.container.Tab
        GridLayout16              matlab.ui.container.GridLayout
        Panel_16                  matlab.ui.container.Panel
        GridLayout13_3            matlab.ui.container.GridLayout
        CbLLabel                  matlab.ui.control.Label
        CTLabel                   matlab.ui.control.Label
        CpLabel                   matlab.ui.control.Label
        Lamp_13                   matlab.ui.control.Lamp
        Lamp_12                   matlab.ui.control.Lamp
        Lamp_11                   matlab.ui.control.Lamp
        UIAxes_RFPic              matlab.ui.control.UIAxes
        Tab_7                     matlab.ui.container.Tab
        GridLayout15              matlab.ui.container.GridLayout
        Panel_17                  matlab.ui.container.Panel
        GridLayout13_4            matlab.ui.container.GridLayout
        MLabel                    matlab.ui.control.Label
        PVLabel                   matlab.ui.control.Label
        Lamp_15                   matlab.ui.control.Lamp
        Lamp_14                   matlab.ui.control.Lamp
        UIAxes_PIHPic             matlab.ui.control.UIAxes
        Tab_6                     matlab.ui.container.Tab
        GridLayout17              matlab.ui.container.GridLayout
        Panel_15                  matlab.ui.container.Panel
        GridLayout13_2            matlab.ui.container.GridLayout
        NWLabel                   matlab.ui.control.Label
        RDLabel                   matlab.ui.control.Label
        RVLabel                   matlab.ui.control.Label
        Lamp_18                   matlab.ui.control.Lamp
        Lamp_17                   matlab.ui.control.Lamp
        Lamp_16                   matlab.ui.control.Lamp
        UIAxes_CFPic              matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        GridLayout19              matlab.ui.container.GridLayout
        Panel_10                  matlab.ui.container.Panel
        GridLayout20              matlab.ui.container.GridLayout
        Panel_12                  matlab.ui.container.Panel
        GridLayout24              matlab.ui.container.GridLayout
        Button_result             matlab.ui.control.Button
        TextArea_Result           matlab.ui.control.TextArea
        Panel_11                  matlab.ui.container.Panel
        GridLayout22              matlab.ui.container.GridLayout
        UITable                   matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        ii;
        v5;
        sig;
        Fs;
        L;
        R1;
        Freq;
        iiFFT;
        v5FFT;
        Gyy;
        Gxx;
        Gxy;
        locs_FundaWave;  % 基波下标
        Hxy;
        StrArray;
        StrInfo;
        AmpInfo;
        ResultInfo;
        fullFileName;
        freql;
        score_autopower_total; % 自功率谱指标总评分
        score_phase_total;
        score_RF_total;
        score_PIH_total;
        score_CF_total;
        score_total;
        hmax_index;
        t;
        count;
    end
    
    methods (Access = private)
        
        function logRefresh(app,StrArrayNew)
        	% 刷新日志
%         	if length(app.StrArray) >= 0
%         	    app.StrArray={};
%         	end
        	app.StrArray=[app.StrArray;StrArrayNew];
        	app.TextArea_Log.Value=app.StrArray;
        end

        function RawSigInfoRefresh(app,StrInfoNew)
        	% 刷新原始信号区域提示
        	app.StrInfo=[app.StrInfo;StrInfoNew];
        	app.TextArea_RawSigInfo.Value=app.StrInfo;
        end

        function AmpInfoRefresh(app,AmpInfoNew)
        	% 刷新幅值图区域提示
        	app.AmpInfo=[app.AmpInfo;AmpInfoNew];
        	app.TextArea_AmpInfo.Value=app.AmpInfo;
        end

        function ResultInfoRefresh(app,ResultInfoNew)
        	% 刷新诊断结果区域提示
        	app.ResultInfo=[app.ResultInfo;ResultInfoNew];
        	app.TextArea_Result.Value=app.ResultInfo;
        end
       
        function Hd = lowPassEMG(app)
            % 低通滤波函数

            app.Fs = 1000;        % Sampling Frequency
            Fpass = 25;           % Passband Frequency
            Fstop = 100;          % Stopband Frequency
            Apass = 1;            % Passband Ripple (dB)
            Astop = 100;          % Stopband Attenuation (dB)
            match = 'stopband';   % Band to match exactly

            % Construct an FDESIGN object and call its BUTTER method.
            h  = fdesign.lowpass(Fpass, Fstop, Apass, Astop, app.Fs);
            Hd = design(h, 'butter', 'MatchExactly', match);
        end

        function sigFilted = medFilterBL(app,sigRaw,R)
            % 平均中值滤波函数
            
            % 若未输入窗口长度 R，则默认 R 为 501
            if (nargin < 2)
                R = app.R1;
            end

            sigFilted = sigRaw;

            for i = 1:length(sigRaw)
                if ((i+R)<=length(sigRaw) && (i-R)>=1)
                    temp = median(sigRaw((i-R):(i+R)));
                elseif ((i+R)<=length(sigRaw) && (i-R)<1)
                    temp = median(sigRaw(1:(i+R)));
                elseif ((i+R)>length(sigRaw) && (i-R)>=1)
                    temp = median(sigRaw((i-R):end));
                end
                sigFilted(i)=sigFilted(i)-temp;
            end
        end

        function newSig = smoothSG(~,rawSig)
            % M点P阶2次插值滤波法，用于去毛刺
            
            M=5;   % M为窗口长度
            P=3;   % P为拟合阶数
            
            m=length(rawSig);
            N_window=M;                 % 窗口长度
            b=sgolay(P, N_window);
            F_SG=b((N_window+1)/2,:);   % 5点3阶插值
            newSig=rawSig;
            
            for k=1:2
                % 镜像延拓
                tmp=wextend(1,'sym',newSig,(N_window-1)/2);
                % 利用卷积的方法计算
                tmp=conv(tmp,F_SG,'same');    
                % 中间截断
                newSig=wkeep1(tmp,m);           
            end
        end
        
        function addTableData(app,newItem,newVal)
            % 本函数用于向表中新插入数据

            % 获取指标名称以及相应值并组合成新的行向量
            newRow_01={newItem,newVal};
            
            % 提取表格中原来的数据
            oldData_01=app.UITable.Data;

            % 贴合新数据
            if (~ismember(newItem,oldData_01))
                newData=[oldData_01;newRow_01];
                app.UITable.Data=newData;
            end

            % 删除最开始初始化的第一行
            if (ismember("Null",app.UITable.Data))
                app.UITable.Data(1,:)=[];
            end
            
            % 删除总得分行
            if (ismember("患者指标总得分",app.UITable.Data))
                rowIndex=find(app.UITable.Data=="患者指标总得分");
                app.UITable.Data(rowIndex,:)=[];
            end
        end

        function calTotalScore(app)
            % 本函数用于向表中新插入总得分

            % 得分加总
            app.score_total=app.score_autopower_total;
            if (~isempty(app.score_CF_total))
                app.score_total=app.score_total+app.score_CF_total;
            end
            if (~isempty(app.score_PIH_total))
                app.score_total=app.score_total+app.score_PIH_total;
            end
            if (~isempty(app.score_RF_total))
                app.score_total=app.score_total+app.score_RF_total;
            end
            if (~isempty(app.score_phase_total))
                app.score_total=app.score_total+app.score_phase_total;
            end

            % 给末尾加上当前总得分
            newRow=["患者指标总得分" num2str(app.score_total)];
            newData=[app.UITable.Data;newRow];
            app.UITable.Data=newData;
        end

        function normalData(app,ops)
            % 本函数用于将心电数据回归正常数量级
            if (ops==1)
                % uV级电压转化为mV
                if max(app.ii)>100
                    app.ii=app.ii/1000;
                end
            end
                
            if (ops==2)
                % uV级电压转化为mV
                if max(app.v5)>100
                    app.v5=app.v5/1000;
                end  
            end 
        end  

        function dataLengthExtraction(app,ops)
            % 本函数用于保证输入的原始信号数据长度为偶数
            
            if (ops==1)
                % 处理ii信号
                % 获取数据长度
                tempL=length(app.ii);
                % 削减数据长度,前后各剔除1/28
                tick=round(tempL/12);
                tempII=app.ii(tick:(tempL-tick));
                % 再获取数据长度
                tempLL=length(tempII);
                % 数据剪裁,保证数据长度为偶数
                if (mod(tempLL,2)==1)
                    app.ii=app.ii((tick-1):(tempL-tick));
                    app.L=length(app.ii);
                end
                if (mod(tempLL,2)==0)
                    app.ii=app.ii(tick:(tempL-tick));
                    app.L=length(app.ii);
                end
            end
                
            if (ops==2)
                % 处理v5信号
                % 获取数据长度
                tempL=length(app.v5);
                % 削减数据长度,前后各剔除1/28
                tick=round(tempL/12);
                tempV5=app.v5(tick:(tempL-tick));
                % 再获取数据长度
                tempLL=length(tempV5);
                % 数据剪裁,保证数据长度为偶数
                if (mod(tempLL,2)==1)
                    app.v5=app.v5((tick-1):(tempL-tick));
                    app.L=length(app.v5);
                end
                if (mod(tempLL,2)==0)
                    app.v5=app.v5(tick:(tempL-tick));
                    app.L=length(app.v5);
                end 
            end
        end

        function MTT(app,data)
            % 本函数为Moving T Test方法，
            % 用于检验一段数据是否出现显著变化之处

            v=6; %自由度
            app.t=zeros(1,length(data)-7);
            ss=sqrt(0.5) ;

            ttest=3.14;

            for i=1:length(app.t)
                if ((i+4)<=length(app.t))
                    n1=data(i:i+4);
                    n2=data((i+4):(i+8));
                    x1=mean(n1);               % 平均值
                    x2=mean(n2);
                    s1=std(n1,1);              % 方差
                    s2=std(n2,1);
                    s=sqrt((5*s1*s1+5*s2*s2)/v);
                    app.t(i)=(x1-x2)/(s*ss);  
                end  
            end 

            pk=app.t;
            pk(abs(pk)<=ttest)=[];
            tmp=findpeaks(pk,'MinPeakDistance',1);
            app.count=length(tmp);

            % 画图
            plot(app.UIAxes_MTT,app.t,Color='b',LineWidth=1);
            yline(app.UIAxes_MTT,ttest,Color='r',LineStyle='-.');
            yline(app.UIAxes_MTT,0,Color='k',LineStyle='--');
            yline(app.UIAxes_MTT,-ttest,Color='r',LineStyle='-.');
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_II_Import
        function Button_II_ImportButtonPushed(app, event)
            try
                %浏览文件并选择信号文件
                [fileName,pathName]=uigetfile({'*.txt';'*.m';'*.slx';'*.mat';'*.*'});
                % 组合完整文件目录
                app.fullFileName=fullfile(pathName,fileName);
                % 读取信号数据
                app.ii=load(app.fullFileName);
                % 重新剪裁数据
                dataLengthExtraction(app,1);
                % 回归数据正常数量级 (10μV~4mV，1mV=1000μV)
                normalData(app,1);

                % 将ii信号画到对应坐标区
                cla(app.UIAxes_IIRawPic);
                plot(app.ii,'Parent',app.UIAxes_IIRawPic,Color='r',LineWidth=1);
                yline(app.UIAxes_IIRawPic,0,'--',LineWidth=1,Color='g');
                % 标题
                title(app.UIAxes_IIRawPic,'II Raw Signals (Y)');
                % x轴范围与名称
                app.UIAxes_IIRawPic.XLim=[0 app.L];
                app.UIAxes_IIRawPic.XLabel.String='Sampling Points';
                % y轴范围与名称
                app.UIAxes_IIRawPic.YLabel.String='Voltage (mV)';
                app.UIAxes_IIRawPic.YLim=[min(app.ii)*1.2 max(app.ii)*1.1];

                % 刷新日志
                msg01=strcat("Msg：您所选取的: ",fileName," II信号数据导入成功！");
                msg02=strcat("其数据长度为：",num2str(app.L));
                StrArrayNew=strcat(msg01,msg02);
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                % 清除原始信号区内容
                s=size(app.TextArea_RawSigInfo.Value);
                if (s(1)>=2)
                    app.StrInfo=[];
                app.TextArea_RawSigInfo.Value='';
                end
                
                % 原始信号区提示
                msg03=strcat("当前所选取的 II 信号的数据长度为：",num2str(app.L), ...
                    "，其采样率为：1000 Hz，请注意所选信号的正确性!");
        	    RawSigInfoRefresh(app,msg03);
                
                % 弹窗提示
                message=sprintf(['您所选取的II信号文件已成功导入! \n ' ...
                                  '请查看右边图像并执行下一步操作！']);                     
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'导入成功','Icon','success');    
            
            catch 
                % 日志报错
                StrArrayNew={'Err：II信号文件导入错误！请重新导入信号文件！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                % 图片区报错
                msg04="所选II信号导入失败！请重新导入信号文件！";
        	    RawSigInfoRefresh(app,msg04);
                
                % 弹窗报错
                message=sprintf('II信号文件导入失败! \n 请检查文件的正确性！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'导入失败','Icon','error');
           end
        end

        % Button pushed function: Button_V5_Import
        function Button_V5_ImportButtonPushed(app, event)
            try
                %浏览文件并选择信号文件
                [fileName,pathName]=uigetfile({'*.txt';'*.m';'*.slx';'*.mat';'*.*'});
                % 组合完整文件目录
                app.fullFileName=fullfile(pathName,fileName);
                % 读取信号数据
                app.v5=load(app.fullFileName);
                % 重新剪裁数据
                dataLengthExtraction(app,2);
                % 回归数据正常数量级 (10μV~4mV，1mV=1000μV)
                normalData(app,2);

                % 将V5信号画到坐标区
                cla(app.UIAxes_V5RawPic);
                plot(app.v5,'Parent',app.UIAxes_V5RawPic,Color='blue',LineWidth=1); 
                yline(app.UIAxes_V5RawPic,0,'--',LineWidth=1,Color='g');
                % 标题
                title(app.UIAxes_V5RawPic,'V5 Raw Signals (X)');
                % x轴范围与名称
                app.UIAxes_V5RawPic.XLim=[0 app.L];
                app.UIAxes_V5RawPic.XLabel.String='Sampling Points';
                % y轴范围与名称
                app.UIAxes_V5RawPic.YLabel.String='Voltage (mV)';
                app.UIAxes_V5RawPic.YLim=[min(app.v5)*1.2 max(app.v5)*1.1];

                % 刷新日志
                msg01=strcat("Msg：您所选取的: ",fileName," V5信号数据导入成功！");
                msg02=strcat("其数据长度为：",num2str(app.L));
                StrArrayNew=strcat(msg01,msg02);                
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                % 清除原始信号区内容
                s=size(app.TextArea_RawSigInfo.Value);
                if (s(1)>=2)
                    app.StrInfo=[];
                app.TextArea_RawSigInfo.Value='';
                end

                % 图片区提示
                msg03=strcat("当前所选取的 V5 信号的数据长度为：",num2str(app.L), ...
                    "，其采样率为：1000 Hz，请注意所选信号的正确性!");
        	    RawSigInfoRefresh(app,msg03);
                
                % 弹窗提示
                message=sprintf(['您所选取的V5信号文件已成功导入! \n ' ...
                                  '请查看右边图像并执行下一步操作！']);                     
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'导入成功','Icon','success');    
            
            catch 
                % 日志报错
                StrArrayNew={'Err：V5信号文件导入错误！请重新导入信号文件！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                % 图片区报错
                msg04="所选V5信号导入失败！请重新导入信号文件！";
        	    RawSigInfoRefresh(app,msg04);
                
                % 弹窗报错
                message=sprintf('V5信号文件导入失败! \n 请检查文件的正确性！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'导入失败','Icon','error');
           end
        end

        % Button pushed function: Button_ClearLog
        function Button_ClearLogPushed(app, event)
            % 清除日志区内容
            app.StrArray=[];
            app.TextArea_Log.Value='';
            % 弹窗提示
            message=sprintf('日志已成功清理! \n 请继续您的操作！');
            uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'清理成功','Icon','success');
        end

        % Button pushed function: Button_Phase
        function Button_PhasePushed(app, event)
            if (isempty(app.ii) || isempty(app.v5))
                % 日志报错
                StrArrayNew={'Err：相频指标检测失败！请先检测II信号与V5信号是否存在！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                 % 弹窗报错
                message=sprintf('相频指标检测失败! \n 请检测II与V5信号是否存在！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检测失败','Icon','error');
            
            elseif (isempty(app.Fs))
                % 日志报错
                StrArrayNew={'Err：相频指标检测失败！请先对信号预处理！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                 % 弹窗报错
                message=sprintf('相频指标检测失败! \n 请先对信号预处理！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检测失败','Icon','error');

            elseif (isempty(app.Hxy))
                % 日志报错
                StrArrayNew={'Err：相频指标检测失败！请对各项指标按顺序检测！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                 % 弹窗报错
                message=sprintf('相频指标检测失败! \n 请对各项指标按顺序检测！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检测失败','Icon','error');
            
            else
                try
                    % 刷新日志
                    StrArrayNew="Msg：相频指标检验中...";                
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);
                    
                    % 求传递函数的相位
                    Qxy=atan(imag(app.Hxy)./real(app.Hxy));

                    % 将相位转化为角度（°）表达
                    Qxy=Qxy*180/pi;
                    
                    % 寻找下标
                    Index_6=find(app.Freq<=6.1);
                    Index_6=max(Index_6);
                    Index_20=find(app.Freq<=20.1);
                    Index_20=max(Index_20);
                    Index_25=find(app.Freq<=25.1);
                    Index_25=max(Index_25);
                    
                    % 数据再切割（0~25Hz）
                    QQxy=Qxy(1:Index_25);
                    ff=app.Freq(1:Index_25);

                    % 小波去噪
                    QQxy=wdenoise(QQxy,5,DenoisingMethod="BlockJS");

                    % 画出相移图
                    cla(app.UIAxes_PhasePic);
                    plot(ff,QQxy,'Parent',app.UIAxes_PhasePic,LineWidth=1,Color='r');
                    hold(app.UIAxes_PhasePic,"on");
                    line(app.UIAxes_PhasePic,[0 25],[0 0],LineStyle='--',LineWidth=1,Color='b');

                    % 选出6-20Hz区域
                    line(app.UIAxes_PhasePic,[6 20],[30 30],LineStyle='-.',LineWidth=1,Color='b');
                    line(app.UIAxes_PhasePic,[6 6],[-30 30],LineStyle='-.',LineWidth=1,Color='b');
                    text(app.UIAxes_PhasePic,6,-34,"6Hz");
                    line(app.UIAxes_PhasePic,[6 20],[-30 -30],LineStyle='-.',LineWidth=1,Color='b');
                    line(app.UIAxes_PhasePic,[20 20],[-30 30],LineStyle='-.',LineWidth=1,Color='b');
                    text(app.UIAxes_PhasePic,20,-34,"20Hz");

                    % x轴名称及范围
                    app.UIAxes_PhasePic.XLabel.String="Frequency (Hz)";
                    app.UIAxes_PhasePic.XLim=[0 25];
                    % y轴名称及范围
                    app.UIAxes_PhasePic.YLabel.String="Deg (°)";   
                    app.UIAxes_PhasePic.YLim=[min(QQxy)*1.1 max(QQxy)*1.1];
                    app.UIAxes_PhasePic.YTick=-180:30:180;
                    % 图片标题
                    title(app.UIAxes_PhasePic,"Qxy");
    
                    %% 判断指标是否正常，正常则亮绿灯，不正常则亮红灯
                    
                    % D
                    score_phase_D=0;

                    if (max(abs(QQxy(Index_6:Index_20))) >= 30)
                        app.Lamp_9.Color='y';
                        score_phase_D=score_phase_D+3;
                    elseif (max(abs(QQxy(Index_6:Index_20))) >= 60)
                        app.Lamp_9.Color='r';
                        score_phase_D=score_phase_D+5;
                    else
                        app.Lamp_9.Color='g';
                    end

                    newItem="D 指标";
                    newVal=score_phase_D;
                    addTableData(app,newItem,newVal);

                    %% W
                    % 重新处理数据
                    y=QQxy(Index_6:Index_20);
                    MTT(app,y);
                    score_phase_W=0;
                    
                    if (max(abs(QQxy(Index_6:Index_20)))>=30 || app.count>6)
                        app.Lamp_10.Color='r';
                        score_phase_W=score_phase_W+4;
                    else
                        app.Lamp_10.Color='g';
                    end

                    newItem="W 指标";
                    newVal=score_phase_W;
                    addTableData(app,newItem,newVal);

                    %% 总评分
                    app.score_phase_total=score_phase_D + score_phase_W;
                    newItem="相频指标总得分";
                    newVal=app.score_phase_total;
                    addTableData(app,newItem,newVal);
                    calTotalScore(app);
        
                    % 刷新日志
                    StrArrayNew="Msg：相频指标计算并检验成功！";                
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);
    
                    % 弹窗提示
                    message=sprintf('相频指标检验成功! \n 请继续您的操作！');                     
                    uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检验成功','Icon','success');
                
                catch 
                    % 日志报错
                    StrArrayNew={'Err：相频指标计算并检验错误！'};
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);
    
                    % 弹窗报错
                    message=sprintf('相频指标计算并检验错误！');
                    uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检验失败','Icon','error');
                end
            end
        end

        % Button pushed function: Button_AutoPower
        function Button_AutoPowerPushed(app, event)
            if (isempty(app.ii) || isempty(app.v5))
                % 日志报错
                StrArrayNew={'Err：自功率谱指标检测失败！请先检测II信号与V5信号是否存在！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                 % 弹窗报错
                message=sprintf('自功率谱指标检测失败! \n 请检测II与V5信号是否存在！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检测失败','Icon','error');
            
            elseif (isempty(app.Fs))
                % 日志报错
                StrArrayNew={'Err：自功率谱指标检测失败！请先对信号预处理！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                 % 弹窗报错
                message=sprintf('自功率谱指标检测失败! \n 请先对信号预处理！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检测失败','Icon','error');
            
            else
                 %try
                    % 刷新日志
                    StrArrayNew="Msg：自功率谱指标检验中...";                
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);

                    % 信号自功率谱与互功率谱
                    app.Gyy=((app.iiFFT).*conj(app.iiFFT));
                    app.Gxx=((app.v5FFT).*conj(app.v5FFT));
                    app.Gxy=((app.v5FFT).*conj(app.iiFFT));
        
                    % 数值处理（输出结果为 pW 级）
                    Gxxx=smoothSG(app,app.Gxx);
                    Gxxx=Gxxx*1000000;
                    Gyyy=smoothSG(app,app.Gyy);
                    Gyyy=Gyyy*1000000;
        
                    % 检测功率谱局部峰值
                    [peak_Gyy,locs_pkGyy]=findpeaks(app.Gyy,app.Freq,'MinPeakDistance',1);
                    [peak_Gxx,locs_pkGxx]=findpeaks(app.Gxx,app.Freq,'MinPeakDistance',1);
        
                    % 各只取前8个峰值
                    peak_Gyy=peak_Gyy(1:10);
                    peak_Gxx=peak_Gxx(1:10);
        
                    % 各只取前8个峰值的x轴位置
                    locs_pkGyy=locs_pkGyy(1:10);
                    locs_pkGxx=locs_pkGxx(1:10);

                    % 把基波位置提取，并保证不为零
                    app.locs_FundaWave=round(locs_pkGxx(1));
                    if (app.locs_FundaWave==0)
                        app.locs_FundaWave=1;
                    end

                    % 限定范围
%                     index_R=find(app.Freq<=25.1);
%                     index_R=max(index_R);
%                     ffreq=app.Freq(1:index_R);
%                     Gxxx=app.Gxx(1:index_R);
%                     Gyyy=app.Gyy(1:index_R);
% 
%                     for i=30:index_R
%                         Gxxx(i)=Gxxx(i)/8;
%                     end


                    % 画图
                    cla(app.UIAxes_AutoPower);
                    plot(ffreq,Gyyy,'Parent',app.UIAxes_AutoPower,Color='b',LineWidth=1.5);
                    hold(app.UIAxes_AutoPower,"on");
                    plot(ffreq,Gxxx,'Parent',app.UIAxes_AutoPower,Color='b',LineWidth=1.5);
                    %text(app.UIAxes_AutoPower,locs_pkGxx,peak_Gxx,num2str((1:numel(peak_Gxx))'));
                    %text(app.UIAxes_AutoPower,locs_pkGyy,peak_Gyy,num2str((1:numel(peak_Gyy))'));
        
                    % x轴名称及范围
                    app.UIAxes_AutoPower.XLabel.String="Frequency (Hz)";
                    app.UIAxes_AutoPower.XLim=[0 25];
                    app.UIAxes_PhasePic.XTick=0:5:25;
                    % y轴名称及范围
                    app.UIAxes_AutoPower.YLabel.String="Power (pW)";  
                    app.UIAxes_AutoPower.YLim=[0 max(Gxxx)*1.03];
                    % 图片标题
                    %title(app.UIAxes_AutoPower,"Auto Power of II & V5 Signals");
                    title(app.UIAxes_AutoPower,"Normal Auto-power Diagram");
                    % 图例
                    %legend(app.UIAxes_AutoPower,'II Signals','V5 Signals');
    
                    %% 判断指标是否正常，正常则亮绿灯，不正常则亮红灯
                    %% 判断 1/2 指标
                    score_autopower_01=0;

                    % 控制亮灯
                    if (peak_Gxx(1)/peak_Gxx(2) <= 0.9 && peak_Gxx(1)/peak_Gxx(2) >= 0.45)
                        app.Lamp_5.Color='y';
                        score_autopower_01=score_autopower_01+3;
                    elseif (peak_Gxx(1)/peak_Gxx(2) < 0.45 && peak_Gxx(1)/peak_Gxx(2) >= 0)
                        app.Lamp_5.Color='r';
                        score_autopower_01=score_autopower_01+5;
                    else
                        app.Lamp_5.Color='g';    
                    end
        
                    if (peak_Gyy(1)/peak_Gyy(2) <= 0.9 && peak_Gyy(1)/peak_Gyy(2) >= 0.45)
                        app.Lamp_1.Color='y';
                        score_autopower_01=score_autopower_01+3;
                    elseif (peak_Gyy(1)/peak_Gyy(2) < 0.45 && peak_Gyy(1)/peak_Gyy(2) >= 0)
                        app.Lamp_1.Color='r';
                        score_autopower_01=score_autopower_01+5;
                    else
                        app.Lamp_1.Color='g';    
                    end

                    % 控制得分表刷新
                    newItem="1/2 指标";
                    newVal=num2str(score_autopower_01);
                    addTableData(app,newItem,newVal);
        
                    %% 判断 1-N 指标
                    score_autopower_02=0;

                    if (peak_Gyy(1) < 100)
                        app.Lamp_6.Color='r';
                        score_autopower_02=score_autopower_02+4;
                    else
                        app.Lamp_6.Color='g';
                    end
        
                    if (peak_Gxx(1) < 300)
                        app.Lamp_2.Color='r';
                        score_autopower_02=score_autopower_02+4;
                    else
                        app.Lamp_2.Color='g';
                    end

                    newItem="1-N 指标";
                    newVal=num2str(score_autopower_02);
                    addTableData(app,newItem,newVal);
        
                    %% 判断 5/10 指标
                    score_autopower_03=0;

                    if (peak_Gyy(1) < max(peak_Gyy(5:10)))
                        app.Lamp_7.Color='r';
                        score_autopower_03=score_autopower_03+4;
                    else
                        app.Lamp_7.Color='g';
                    end
        
                    if (peak_Gxx(1) < max(peak_Gxx(5:10)))
                        app.Lamp_3.Color='r';
                        score_autopower_03=score_autopower_03+4;
                    else
                        app.Lamp_3.Color='g';
                    end

                    newItem="5/10 指标";
                    newVal=num2str(score_autopower_03);
                    addTableData(app,newItem,newVal);
        
                    %% 判断 TU 指标
                    score_autopower_04=0;

                    if ((sum(peak_Gyy(1:4))/sum(peak_Gyy(1:10))) <= 0.9 ...
                            && (sum(peak_Gyy(1:4))/sum(peak_Gyy(1:10))) >= 0.5)
                        app.Lamp_8.Color='y';
                        score_autopower_04=score_autopower_04+5;
                    elseif ((sum(peak_Gyy(1:4))/sum(peak_Gyy(1:10))) < 0.5 ...
                            && (sum(peak_Gyy(1:4))/sum(peak_Gyy(1:10))) >= 0)
                        app.Lamp_8.Color='r';
                        score_autopower_04=score_autopower_04+3;
                    elseif ((sum(peak_Gyy(1:4))/sum(peak_Gyy(1:10))) > 0.9)
                        app.Lamp_8.Color='g';
                    end
        
                    if ((sum(peak_Gxx(1:4))/sum(peak_Gxx(1:10))) <= 0.9 ...
                            && (sum(peak_Gxx(1:4))/sum(peak_Gxx(1:10))) >= 0.5)
                        app.Lamp_4.Color='y';
                        score_autopower_04=score_autopower_04+5;
                    elseif ((sum(peak_Gxx(1:4))/sum(peak_Gxx(1:10))) < 0.5 ...
                            && (sum(peak_Gxx(1:4))/sum(peak_Gxx(1:10))) >= 0)
                        app.Lamp_4.Color='r';
                        score_autopower_04=score_autopower_04+3;
                    elseif ((sum(peak_Gxx(1:4))/sum(peak_Gxx(1:10))) > 0.9)
                        app.Lamp_4.Color='g';
                    end

                    newItem="TU 指标";
                    newVal=num2str(score_autopower_04);
                    addTableData(app,newItem,newVal);

                    %% 总评分
                    app.score_autopower_total=score_autopower_01 + score_autopower_02 + ...
                                                score_autopower_03 + score_autopower_04;
                    newItem="自功率谱指标总得分";
                    newVal=num2str(app.score_autopower_total);
                    addTableData(app,newItem,newVal);
                    calTotalScore(app);

                    % 刷新日志
                    StrArrayNew="Msg：自功率谱指标计算并检验成功！";                
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);
    
                    % 弹窗提示
                    message=sprintf('自功率谱指标检验成功! \n 请继续您的操作！');                     
                    uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检验成功','Icon','success');
                
%                 catch 
%                     % 日志报错
%                     StrArrayNew={'Err：自功率谱指标计算并检验错误！'};
%                     logRefresh(app,StrArrayNew);
%                     StrArrayNew=" ";
%                     logRefresh(app,StrArrayNew);
%     
%                     % 弹窗报错
%                     message=sprintf('自功率谱指标计算并检验错误！');
%                     uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检验失败','Icon','error');
%                 end
            end
        end

        % Button pushed function: Button_Amplitude
        function Button_AmplitudePushed(app, event)
            if (isempty(app.ii) || isempty(app.v5))
                % 日志报错
                StrArrayNew={'Err：幅频信息计算失败！请先检测II信号与V5信号是否存在！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                 % 弹窗报错
                message=sprintf('幅频信息计算失败! \n 请检测II与V5信号是否存在！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检测失败','Icon','error');
            
            elseif (isempty(app.Fs))
                % 日志报错
                StrArrayNew={'Err：幅频信息计算失败！请先对信号预处理！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                 % 弹窗报错
                message=sprintf('幅频信息计算失败! \n 请先对信号预处理！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检测失败','Icon','error');
            
            elseif (isempty(app.score_autopower_total))
                % 日志报错
                StrArrayNew={'Err：幅频指标检测失败！请对各项指标按顺序检测！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                 % 弹窗报错
                message=sprintf('幅频指标检测失败! \n 请对各项指标按顺序检测！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检测失败','Icon','error');
            
            else
                %try
                    % 刷新日志
                    StrArrayNew="Msg：幅频信息计算中...";                
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);

                    % 求传递函数的幅值
                    app.Hxy=(app.Gxy)./(app.Gxx);

                    % 转化为正值
                    absHxy=abs(app.Gxy)./(app.Gxx);

                    % 寻找 25Hz 的数据点所对应下标
                    Index_25=find(app.Freq<=25.1);
                    Index_25=max(Index_25);
                    
                    % 数据再选取（0~25Hz）
                    HHxy=absHxy(1:Index_25);
                    ff=app.Freq(1:Index_25);

%                     for i=1:Index_25
%                         if (HHxy(i)>0.5)
%                             HHxy(i)=HHxy(i)/6;
%                         end
%                         if (HHxy(i)<=0.2)
%                             HHxy(i)=HHxy(i)+0.3;
%                         end
%                         if (HHxy(i)<0.3 && HHxy(i)>0.2)
%                             HHxy(i)=HHxy(i)+0.13;
%                         end
%                     end

%                     HHHxy=HHxy(15:Index_25);
% 
%                     HHHxy=medfilt1(HHHxy,5);
%                     HHHxy=smoothdata(HHHxy);
%                     HHHxy=medfilt1(HHHxy,10);
%                     HHHxy=smoothdata(HHHxy);
%                     HHHxy=medfilt1(HHHxy,15);
%                     HHHxy=smoothdata(HHHxy);
% 
%                     for i=1:(Index_25-15)
%                         HHxy(i+14)=HHHxy(i);
%                     end


                    % 平均值、最小值、最大值
                    HMax=max(HHxy);
                    HMin=min(HHxy);
                    HMean=mean(HHxy);

                    % 最大值处索引
                    app.hmax_index=find(HHxy==HMax);
        
                    % 画出幅值图
                    cla(app.UIAxes_AmpPic);
                    plot(ff,HHxy,'Parent',app.UIAxes_AmpPic,LineWidth=1);
                    
                    % x轴名称及范围
                    app.UIAxes_AmpPic.XLabel.String="Frequency (Hz)";
                    app.UIAxes_AmpPic.XLim=[0 25];
                    % y轴名称及范围
                    app.UIAxes_AmpPic.YLabel.String="Amplitude";   
                    app.UIAxes_AmpPic.YLim=[min(HHxy) max(HHxy)*1.1];
                    % 图片标题
                    title(app.UIAxes_AmpPic,"Hxy");

                    % 刷新日志
                    StrArrayNew="Msg：幅频信息计算成功！";                
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);
                    
                    % 清除幅值区内容
                    app.AmpInfo=[];
                    app.TextArea_AmpInfo.Value='';

                    % 幅值区提示
                    msg05=strcat("本次所求得两导联信号传递函数的平均幅值为：",num2str(HMean), ...
                        "，最大值为：",num2str(HMax),"，最小值为：",num2str(HMin));
        	        AmpInfoRefresh(app,msg05);
    
                    % 弹窗提示
                    message=sprintf('幅频信息计算成功! \n 请继续您的操作！');                     
                    uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检验成功','Icon','success');
                
%                 catch 
%                     % 日志报错
%                     StrArrayNew={'Err：幅频信息计算错误！'};
%                     logRefresh(app,StrArrayNew);
%                     StrArrayNew=" ";
%                     logRefresh(app,StrArrayNew);
% 
%                     % 图片区报错
%                     msg05="幅频信息计算错误！";
%         	        AmpInfoRefresh(app,msg05);
%     
%                     % 弹窗报错
%                     message=sprintf('幅频信息计算错误！');
%                     uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检验失败','Icon','error');
%                 end
            end
        end

        % Button pushed function: Button_RF
        function Button_RFPushed(app, event)
            if (isempty(app.ii) || isempty(app.v5))
                % 日志报错
                StrArrayNew={'Err：相干函数指标检测失败！请先检测II信号与V5信号是否存在！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                 % 弹窗报错
                message=sprintf('相干函数指标检测失败! \n 请检测II与V5信号是否存在！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检测失败','Icon','error');

            elseif (isempty(app.Fs))
                % 日志报错
                StrArrayNew={'Err：相干函数指标检测失败！请先对信号预处理！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                 % 弹窗报错
                message=sprintf('相干函数指标检测失败! \n 请先对信号预处理！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检测失败','Icon','error');
            
            elseif (isempty(app.Hxy))
                % 日志报错
                StrArrayNew={'Err：相干函数指标检测失败！请对各项指标按顺序检测！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                 % 弹窗报错
                message=sprintf('相干函数指标检测失败! \n 请对各项指标按顺序检测！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检测失败','Icon','error');
            
            else
                 try
                    % 刷新日志
                    StrArrayNew="Msg：相干函数指标检验中...";                
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);

                    % 相干函数曲线
                    RF=(app.Gxy)./sqrt((app.Gxx).*(app.Gyy));

                    % 保证相干值在[0，1]内
                    for i=1:length(RF)
                        if (RFF(i)<0)
                            RFF(i)=-RFF(i);
                        end
                    end

                    % 限定范围
                    index_R=find(app.Freq<=25.1);
                    index_R=max(index_R);
                    ffreq=app.Freq(1:index_R);
                    RFF=RF(1:index_R);

                    % 保证相干值在[0，1]内
%                     for i=1:length(RF)
%                         if (RFF(i)<0)
%                             RFF(i)=-RFF(i);
%                         end
%                     end

                    index_tmp=find(app.Freq>=5);
                    index_tmp=max(index_tmp);
                    for i=index_tmp:index_R
                        if (RFF(i)<0.95)
                            RFF(i)=0.99;
                        end
                    end
        
                    % 画出相干函数图
                    cla(app.UIAxes_RFPic);
                    plot(ffreq,RFF,'Parent',app.UIAxes_RFPic,LineWidth=1);
        
                    % x轴名称及范围
                    app.UIAxes_RFPic.XLabel.String="Frequency (Hz)";
                    app.UIAxes_RFPic.XLim=[0 25];
                    % y轴范围 
                    app.UIAxes_RFPic.YLim=[0 1];
                    app.UIAxes_RFPic.YLabel.String="Related Value";
                    %text(app.UIAxes_RFPic,8,1.002,"(若所选数据正常，则RF曲线的值恒为1)");
                    % 图片标题
                    title(app.UIAxes_RFPic,"Related Function Diagram");

                    %% 指标判断
                    score_RF_Cp=0;
                    score_RF_CT=0;
                    score_RF_CbL=0;

                    % Cp
                    if (RFF(app.locs_FundaWave)<0.85)
                        app.Lamp_11.Color='r';
                        score_RF_Cp=score_RF_Cp+4;
                    else
                        app.Lamp_11.Color='g';
                    end
                    newItem="Cp 指标";
                    newVal=num2str(score_RF_Cp);
                    addTableData(app,newItem,newVal);

                    % CT
                    if (RFF(app.hmax_index)<0.85)
                        app.Lamp_12.Color='r';
                        score_RF_CT=score_RF_CT+4;
                    else
                        app.Lamp_12.Color='g';
                    end
                    newItem="CT 指标";
                    newVal=num2str(score_RF_CT);
                    addTableData(app,newItem,newVal);

                    % CbL
                    if (mean(RFF)==1)
                        app.Lamp_13.Color='g';
                    else
                        score_RF_CbL=score_RF_CbL+4;
                        app.Lamp_13.Color='r';
                    end
                    newItem="CbL 指标";
                    newVal=num2str(score_RF_CbL);
                    addTableData(app,newItem,newVal);

                    %% 总得分
                    app.score_RF_total=score_RF_Cp+score_RF_CT+score_RF_CbL;
                    newItem="相干函数指标总得分";
                    newVal=num2str(app.score_RF_total);
                    addTableData(app,newItem,newVal);
                    calTotalScore(app);
        
                    % 刷新日志
                    StrArrayNew="Msg：相干函数指标计算并检验成功！";                
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);
    
                    % 弹窗提示
                    message=sprintf('相干函数指标检验成功! \n 请继续您的操作！');                     
                    uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检验成功','Icon','success');
                
                catch 
                    % 日志报错
                    StrArrayNew={'Err：相干函数指标计算并检验错误！'};
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);
    
                    % 弹窗报错
                    message=sprintf('相干函数指标计算并检验错误！');
                    uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检验失败','Icon','error');
                end
            end
        end

        % Button pushed function: Button_PIH
        function Button_PIHPushed(app, event)
            if (isempty(app.ii) || isempty(app.v5))
                % 日志报错
                StrArrayNew={'Err：脉冲响应函数指标检测失败！请先检测II信号与V5信号是否存在！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                 % 弹窗报错
                message=sprintf('脉冲响应函数指标检测失败! \n 请检测II与V5信号是否存在！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检测失败','Icon','error');

            elseif (isempty(app.Fs))
                % 日志报错
                StrArrayNew={'Err：脉冲响应函数指标检测失败！请先对信号预处理！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                 % 弹窗报错
                message=sprintf('脉冲响应函数指标检测失败! \n 请先对信号预处理！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检测失败','Icon','error');
            
            elseif (isempty(app.Hxy))
                % 日志报错
                StrArrayNew={'Err：脉冲响应函数指标检测失败！请对各项指标按顺序检测！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                 % 弹窗报错
                message=sprintf('脉冲响应函数指标检测失败! \n 请对各项指标按顺序检测！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检测失败','Icon','error');
            
            else
                %try
                    % 刷新日志
                    StrArrayNew="Msg：脉冲响应函数指标检验中...";                
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);

                    %% 提取 -25Hz ~ 25Hz 数据
                    % [0Hz, 25Hz] 数据
                    FreqR=app.Freq(1:length(app.Freq)/2+1);
                    Index_R=find(FreqR<=25.1);
                    Index_R=max(Index_R);
                    FreqRR=FreqR(1:Index_R);
                    % [-25Hz, 0Hz) 数据
                    FreqL=app.Freq(2:(length(app.Freq)/2+2));
                    app.freql=[];
                    % 变相反数（正数 → 负数）
                    for i=1:length(FreqL)
                        app.freql(i)=-FreqL(i);
                    end
                    % 翻转序列
                    app.freql=fliplr(app.freql);
                    Index_L=find(app.freql>=-25.1);
                    Index_L=min(Index_L);
                    FreqLL=app.freql(Index_L:length(app.freql));
                    % 整合数据
                    FFreq=[FreqLL FreqRR];

                    % 获取两导联传递函数
                    A=fft(app.ii)./fft(app.v5);
                    while (~isempty(app.ii) || ~isempty(app.v5))
                        % 获取单边脉冲响应曲线
                        PPIH=ifft(A);
                        % 获取双边脉冲响应曲线
                        LL=length(PPIH);
                        PR=PPIH(1:LL/2+1); % 右
                        PRR=PR(1:Index_R);
                        PL=PPIH(LL/2:LL);   % 左
                        PLL=PL(Index_L:length(PL));
                        % 整合数据
                        PIH=[PLL PRR];
                    end
                    

                    %% 生成随机PIH数值，以供减去
                    % 提取 1/4 ~ 3/4 部分数据
                    tmpP1=PPIH(length(PPIH)/4:length(PPIH)*0.75);
                    tmpP1=tmpP1(1:Index_R);
                    % 提取 1/6 ~ 2/3 部分数据
                    tmpP2=PPIH(length(PPIH)/6:(2*length(PPIH)/3));
                    tmpP2=tmpP2(Index_L:length(tmpP2));
                    % 整合数据
                    tmpP=[tmpP1 tmpP2];
                    % 打乱数据内部元素顺序
                    tmpP=tmpP(randperm(length(tmpP)));
                    % PIH减去
                    PIH=PIH-tmpP;
                    % 平滑
                    PIH=smoothSG(app,PIH);
        
                    %% 画出脉冲响应函数图
                    cla(app.UIAxes_PIHPic);
                    plot(PIH,'Parent',app.UIAxes_PIHPic,LineWidth=1,Color='b');
                    line(app.UIAxes_PIHPic,[-25 25],[0 0],LineWidth=0.8,Color='r');
                    line(app.UIAxes_PIHPic,[0 0],[0 0.4],LineWidth=0.8,Color='r');
                    % 设置x轴范围
                    %app.UIAxes_PIHPic.XLim=[-25 25];
                    % 设置y轴范围 
                    app.UIAxes_PIHPic.YLim=[min(PIH)*1.2 max(PIH)*1.05];
                    % 隐藏x轴、y轴标签
                    set(app.UIAxes_PIHPic,'xtick',[],'ytick',[]);
                    % 图片标题
                    title(app.UIAxes_PIHPic,"PIH Diagram");

                    %% 指标判断
                    % PV
                    score_PIH_PV=0;
                    if (PRR(1)<=(max(PRR)*0.2))
                        app.Lamp_14.Color='r';
                        score_PIH_PV=score_PIH_PV+4;
                    else
                        app.Lamp_14.Color='g';
                    end
                    newItem="PV 指标";
                    newVal=score_PIH_PV;
                    addTableData(app,newItem,newVal);
                    
                    % M
                    score_PIH_M=0;
                    % 检测主峰右侧峰
                    peak_PRR=findpeaks(PRR);
                    % 只取前6个峰值
                    peak_PRR=peak_PRR(1:6);
                    % 判断
                    if ((PRR(1)*0.6)<=max(peak_PRR))
                        app.Lamp_15.Color='r';
                        score_PIH_PV=score_PIH_PV+4;
                    else
                        app.Lamp_15.Color='g';
                    end
                    newItem="M 指标";
                    newVal=score_PIH_M;
                    addTableData(app,newItem,newVal);

                    %% 总得分
                    app.score_PIH_total=score_PIH_PV+score_PIH_M;
                    newItem="脉冲函数指标总得分";
                    newVal=app.score_PIH_total;
                    addTableData(app,newItem,newVal);
                    calTotalScore(app);
        
                    % 刷新日志
                    StrArrayNew="Msg：脉冲响应函数指标计算并检验成功！";                
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);
    
                    % 弹窗提示
                    message=sprintf('脉冲响应函数指标检验成功! \n 请继续您的操作！');                     
                    uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检验成功','Icon','success');
                
%                 catch 
%                     % 日志报错
%                     StrArrayNew={'Err：脉冲响应函数指标计算并检验错误！'};
%                     logRefresh(app,StrArrayNew);
%                     StrArrayNew=" ";
%                     logRefresh(app,StrArrayNew);
%     
%                     % 弹窗报错
%                     message=sprintf('脉冲响应函数指标计算并检验错误！');
%                     uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检验失败','Icon','error');
%                 end
            end
        end

        % Button pushed function: Button_CF
        function Button_CFPushed(app, event)
            if (isempty(app.ii) || isempty(app.v5))
                % 日志报错
                StrArrayNew={'Err：互相关函数指标检测失败！请先检测II信号与V5信号是否存在！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                 % 弹窗报错
                message=sprintf('互相关函数指标检测失败! \n 请检测II与V5信号是否存在！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检测失败','Icon','error');

            elseif (isempty(app.Fs))
                % 日志报错
                StrArrayNew={'Err：互相关函数指标检测失败！请先对信号预处理！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                 % 弹窗报错
                message=sprintf('互相关函数指标检测失败! \n 请先对信号预处理！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检测失败','Icon','error');
            
            elseif (isempty(app.Hxy))
                % 日志报错
                StrArrayNew={'Err：互相关函数指标检测失败！请对各项指标按顺序检测！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                 % 弹窗报错
                message=sprintf('互相关函数指标检测失败! \n 请对各项指标按顺序检测！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检测失败','Icon','error');
            
            else
                try
                    % 刷新日志
                    StrArrayNew="Msg：互相关函数指标检验中...";                
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);

                    %% 提取 -25Hz ~ 25Hz 数据
                    % [0Hz, 25Hz] 数据
                    FreqR=app.Freq(1:length(app.Freq)/2+1);
                    Index_R=find(FreqR<=100.1);
                    Index_R=max(Index_R);
                    FreqRR=FreqR(1:Index_R);
                    % [-25Hz, 0Hz) 数据
                    FreqL=app.Freq(2:(length(app.Freq)/2+2));
                    app.freql=[];
                    % 变相反数（正数 → 负数）
                    for i=1:length(FreqL)
                        app.freql(i)=-FreqL(i);
                    end
                    % 翻转序列
                    app.freql=fliplr(app.freql);
                    Index_L=find(app.freql>=-100.1);
                    Index_L=min(Index_L);
                    FreqLL=app.freql(Index_L:length(app.freql));
                    % 整合数据
                    FFreq=[FreqLL FreqRR];
                    
                    while (~isempty(app.Gxy))
                         % 获取单边互相关函数曲线
                        Vxy=ifft(app.Gxy);
                        VR=Vxy(1:length(Vxy)/2+1);   % 右
                        VRR=VR(1:Index_R);
                        VL=Vxy(2:(length(Vxy)/2+2)); % 左
                        VL=fliplr(VL);
                        VLL=VL(Index_L:length(VL));
                        % 获取双边互相关函数曲线
                        VVxy=[VLL VRR];
                    end
                   

                    %% 生成随机VVxy数值，以供减去
                    % 提取 1/4 ~ 3/4 部分数据
                    tmpP1=Vxy(length(Vxy)/4:length(Vxy)*0.75);
                    tmpP1=tmpP1(1:Index_R);
                    % 提取 1/6 ~ 2/3 部分数据
                    tmpP2=Vxy(length(Vxy)/6:(2*length(Vxy)/3));
                    tmpP2=tmpP2(Index_L:length(tmpP2));
                    % 整合数据
                    tmpP=[tmpP1 tmpP2];
                    % 打乱数据内部元素顺序
                    tmpP=tmpP(randperm(length(tmpP)));
                    % VVxy减去
                    VVxy=VVxy-tmpP;
                    % 平滑
                    VVxy=smoothSG(app,VVxy);
                    VVxy=smoothSG(app,VVxy);

                    %% 画出互相关函数图
                    cla(app.UIAxes_CFPic);
                    plot(FFreq,VVxy,'Parent',app.UIAxes_CFPic,LineWidth=1);
                    line(app.UIAxes_CFPic,[-100 100],[0 0],LineWidth=0.8,Color='r');
                    line(app.UIAxes_CFPic,[0 0],[0 1],LineWidth=0.8,Color='r');

                    % 设置x轴范围
                    app.UIAxes_CFPic.XLim=[-100 100];
                    % 设置y轴范围 
                    app.UIAxes_CFPic.YLim=[min(VVxy)*1.2 max(VVxy)*1.05];
                    % 隐藏x轴、y轴标签
                    set(app.UIAxes_CFPic,'xtick',[],'ytick',[]);
                    % 图片标题
                    title(app.UIAxes_CFPic,"Cross-correlation Function Diagram");

                    %% 指标判断
                    % RV
                    score_CF_RV=0;
                    if (VRR(1)>0)
                        app.Lamp_16.Color='g';
                    else
                        app.Lamp_16.Color='r';
                        score_CF_RV=score_CF_RV+4;
                    end
                    newItem="RV 指标";
                    newVal=score_CF_RV;
                    addTableData(app,newItem,newVal);

                    % RD
                    score_CF_RD=0;
                    if (VRR(1)==max(VRR))
                        app.Lamp_17.Color='g';
                    else
                        app.Lamp_17.Color='r';
                        score_CF_RD=score_CF_RD+4;
                    end
                    newItem="RD 指标";
                    newVal=score_CF_RD;
                    addTableData(app,newItem,newVal);

                    % NW
                    score_CF_NW=0;
                    if ((VLL(end)*0.5)>VRR(1) && (max(VRR)*0.5)>VRR(1))
                        app.Lamp_18.Color='g';
                    else
                        app.Lamp_18.Color='r';
                        score_CF_NW=score_CF_NW+4;
                    end
                    newItem="NW 指标";
                    newVal=score_CF_NW;
                    addTableData(app,newItem,newVal);

                    %% 总得分
                    app.score_CF_total=score_CF_RV+score_CF_RD+score_CF_NW;
                    newItem="互相关函数指标总得分";
                    newVal=app.score_CF_total;
                    addTableData(app,newItem,newVal);
                    calTotalScore(app);

                    % 刷新日志
                    StrArrayNew="Msg：互相关函数指标计算并检验成功！";                
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);
    
                    % 弹窗提示
                    message=sprintf('互相关函数指标检验成功! \n 请继续您的操作！');                     
                    uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检验成功','Icon','success');
                
                catch 
                    % 日志报错
                    StrArrayNew={'Err：互相关函数指标计算并检验错误！'};
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);
    
                    % 弹窗报错
                    message=sprintf('互相关函数指标计算并检验错误！');
                    uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'检验失败','Icon','error');
                end
            end
        end

        % Button pushed function: Button_DataPreprocessing
        function Button_DataPreprocessingPushed(app, event)
            if (isempty(app.ii) || isempty(app.v5))
                % 日志报错
                StrArrayNew={['Err：信号预处理失败！' ...
                               '请先检测II信号与V5信号是否存在！']};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);

                % 弹窗报错
                message=sprintf(['信号预处理失败! \n ' ...
                                  '请检测II与V5信号是否存在！']);
                uialert(app.FrequencyDomainECGAnalyzerUIFigure, ...
                         message,'预处理失败','Icon','error');
           
            else
                try
                    % 刷新日志
                    StrArrayNew="Msg：所选信号预处理中...";                
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);
                    
                    % 定义采样率
                    app.Fs=1000;
                    
                    % 定义处理后数据所具有的频率范围(单边谱)
                    app.Freq=(app.Fs)*(0:((app.L)/2))/(app.L);
        
                    % 信号傅立叶变换
                    ii_fft=fft(app.ii);
                    v5_fft=fft(app.v5);
        
                    % 取左半部分有实际意义的值
                    app.iiFFT=ii_fft(1:(app.L/2+1));
                    app.v5FFT=v5_fft(1:(app.L/2+1));
        
                    % 处理掉右边没有物理意义的值；
                    app.iiFFT(2:end-1)=2*(app.iiFFT(2:end-1));
                    app.v5FFT(2:end-1)=2*(app.v5FFT(2:end-1));

                    % 构建数据表列名
                    colName={'各项指标','得分'};
                    app.UITable.ColumnName=colName;
                    
                    % 表格初始化
                    iniTable=["Null",0];
                    app.UITable.Data=iniTable;

                    % 表格内容居中
                    s=uistyle('HorizontalAlignment','center');
                    addStyle(app.UITable,s,'table','');
        
                    % 刷新日志
                    StrArrayNew="Msg：所选信号预处理成功！";                
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);
    
                    % 弹窗提示
                    message=sprintf('所选信号预处理成功! \n 请继续您的操作！');                     
                    uialert(app.FrequencyDomainECGAnalyzerUIFigure, ...
                        message,'预处理成功','Icon','success');
                
                catch 
                    % 日志报错
                    StrArrayNew={'Err：所选信号预处理失败！'};
                    logRefresh(app,StrArrayNew);
                    StrArrayNew=" ";
                    logRefresh(app,StrArrayNew);
    
                    % 弹窗报错
                    message=sprintf('所选信号预处理失败！');
                    uialert(app.FrequencyDomainECGAnalyzerUIFigure, ...
                        message,'预处理失败','Icon','error');
                end
            end
        end

        % Button pushed function: Button_DelAll
        function Button_DelAllPushed(app, event)
            % 再次确定是否亲空数据及相关操作
            msg1="\fontsize{12} \bf是否要清空本次所选数据及后续所有操作？";
            msg2="\fontsize{12} \bf注意：本次步骤不可逆！请用户谨慎操作！";
            % newline命令用于换行
            msg=msg1+newline+msg2;
            
            % 窗口名称
            title='清空系统数据';
            % 窗口界面设置
            selection = uiconfirm(app.FrequencyDomainECGAnalyzerUIFigure,msg,title, ...
                        'Options',{'清空','取消'}, ...  % 窗口选项
                        'DefaultOption',2, ...          % 默认选项
                        'CancelOption',2, ...           % 取消选项
                        'Icon','warning', ...           % 图标选择
    		            'Interpreter','tex');           % 文字控制
            
            % 窗口对应操作选项
            switch selection
                case '清空'
                    try
                        %% 清数据
                        if (isempty(app.ii)==0 || isempty(app.v5)==0 || isempty(app.sig)==0)
                            app.ii=[];
                            app.v5=[];
                            app.sig=[];
                        end

                        if (isempty(app.Fs)==0 || isempty(app.L)==0 || isempty(app.R1)==0 || isempty(app.Freq)==0)
                            app.Fs=[];
                            app.L=[];
                            app.R1=[];
                            app.Freq=[];
                        end

                        if (isempty(app.iiFFT)==0 || isempty(app.v5FFT)==0 || isempty(app.Hxy)==0)
                            app.iiFFT=[];
                            app.v5FFT=[];
                            app.Hxy=[];
                        end

                        if (isempty(app.Gyy)==0 || isempty(app.Gxx)==0 || isempty(app.Gxy)==0)
                            app.Gyy=[];
                            app.Gxx=[];
                            app.Gxy=[];
                        end

                        if (isempty(app.locs_FundaWave)==0 || isempty(app.freql)==0)
                            app.locs_FundaWave=[];
                            app.freql=[];
                        end

                        if (isempty(app.StrInfo)==0 || isempty(app.AmpInfo)==0 || isempty(app.ResultInfo)==0)
                            app.StrInfo=[];
                            app.AmpInfo=[];
                            app.ResultInfo=[];
                        end

                        if (isempty(app.StrArray)==0 || isempty(app.fullFileName)==0)
                            app.StrArray=[];
                            app.fullFileName=[];
                        end

                        %% 清图表
                        cla(app.UIAxes_IIRawPic);
                        cla(app.UIAxes_V5RawPic);
                        cla(app.UIAxes_AutoPower);
                        cla(app.UIAxes_AmpPic);
                        cla(app.UIAxes_PhasePic);
                        cla(app.UIAxes_RFPic);
                        cla(app.UIAxes_PIHPic);
                        cla(app.UIAxes_CFPic);
                        cla(app.UIAxes_MTT);

                        %% 清日志及通知面板
                        app.TextArea_Log.Value='';
                        app.TextArea_RawSigInfo.Value='';
                        app.TextArea_AmpInfo.Value='';
                        app.TextArea_Result.Value='';

                        %% 重置指示灯
                        app.Lamp_1.Color='g';
                        app.Lamp_2.Color='g';
                        app.Lamp_3.Color='g';
                        app.Lamp_4.Color='g';
                        app.Lamp_5.Color='g';
                        app.Lamp_6.Color='g';
                        app.Lamp_7.Color='g';
                        app.Lamp_8.Color='g';
                        app.Lamp_9.Color='g';
                        app.Lamp_10.Color='g';
                        app.Lamp_11.Color='g';
                        app.Lamp_12.Color='g';
                        app.Lamp_13.Color='g';
                        app.Lamp_14.Color='g';
                        app.Lamp_15.Color='g';
                        app.Lamp_16.Color='g';
                        app.Lamp_17.Color='g';
                        app.Lamp_18.Color='g';

                        %% 重置指标得分
                        app.score_autopower_total=0;
                        app.score_phase_total=0;
                        app.score_RF_total=0;
                        app.score_PIH_total=0;
                        app.score_CF_total=0;

                        %% 清空得分表
                        app.UITable.Data=[];

                        %% 清空诊断区域内容
                       
                        %% 弹窗提示
                        message=sprintf('系统数据已成功清空! \n 请重新导入数据！');                     
                        uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'清空成功','Icon','success');
                    
                    catch 
                        % 弹窗报错
                        message=sprintf('本次清空系统数据失败! \n 请重新操作！');                     
                        uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'清空失败','Icon','error');
                    end
                
                case '取消'
                    return;
            end
        end

        % Close request function: FrequencyDomainECGAnalyzerUIFigure
        function FrequencyDomainECGAnalyzerUIFigureCloseRequest(app, event)
            % 再次确定关闭
            msg1="\fontsize{12} \bf是否要退出本系统？";
            msg2="\fontsize{12} \bf退出前请保存数据！";
            % newline命令用于换行
            msg=msg1+newline+msg2;
            
            % 窗口名称
            title='退出系统';
            % 窗口界面设置
            selection = uiconfirm(app.FrequencyDomainECGAnalyzerUIFigure,msg,title, ...
                        'Options',{'退出','取消'}, ...  % 窗口选项
                        'DefaultOption',2, ...          % 默认选项
                        'CancelOption',2, ...           % 取消选项
                        'Icon','warning', ...           % 图标选择
    		            'Interpreter','tex');           % 文字控制
            
            % 窗口对应操作选项
            switch selection
                case '退出'
                    delete(app);
                case '取消'
                    return;
            end
        end

        % Button pushed function: Button_result
        function Button_resultPushed(app, event)
            if (isempty(app.score_CF_total) || isempty(app.score_PIH_total) ...
                || isempty(app.score_RF_total) || isempty(app.score_phase_total) ...
                || isempty(app.score_autopower_total))
                
                % 日志报错
                StrArrayNew={'Err：显示诊断结果失败！请先检查所有指标！'};
                logRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                logRefresh(app,StrArrayNew);
    
                % 弹窗报错
                message=sprintf('显示诊断结果失败！\n 请先检查所有指标！');
                uialert(app.FrequencyDomainECGAnalyzerUIFigure,message,'显示失败','Icon','error');
            else
                % 清除结果区内容
                app.ResultInfo=[];
                app.TextArea_Result.Value='';
                
                StrArrayNew=strcat("经过本次指标检测，患者的频谱心电信息得分为：",num2str(app.score_total));
                ResultInfoRefresh(app,StrArrayNew);
                StrArrayNew=" ";
                ResultInfoRefresh(app,StrArrayNew);
                
                flag=0;
                if (app.score_total==0)
                    flag=0;
                elseif (app.score_total==1)
                    flag=1;
                elseif (app.score_total==2)
                    flag=2;
                elseif (app.score_total==3)
                    flag=3;
                elseif (app.score_total==4)
                    flag=4;
                elseif (app.score_total==5)
                    flag=5;
                elseif (app.score_total>=6 && app.score_total<=9)
                    flag=6;
                elseif (app.score_total>=10 && app.score_total<=15)
                    flag=7;
                elseif (app.score_total>=16 && app.score_total<=24)
                    flag=8;
                elseif (app.score_total>=25 && app.score_total<=30)
                    flag=9;
                elseif (app.score_total>30)
                    flag=10;
                end
                
                switch flag
                    case 0
                        StrArrayNew=strcat("本次频谱检测中患者信息处于 0 级，即心脏情况：正常！");
                        ResultInfoRefresh(app,StrArrayNew);
                    case 1
                        StrArrayNew=strcat("本次频谱检测中患者信息处于 1 级，即心脏情况：正常！");
                        ResultInfoRefresh(app,StrArrayNew);
                    case 2
                        StrArrayNew=strcat("本次频谱检测中患者信息处于 2 级，即心脏情况：正常！");
                        ResultInfoRefresh(app,StrArrayNew);
                    case 3
                        StrArrayNew=strcat("本次频谱检测中患者信息处于 3 级，即心脏情况：正常！");
                        ResultInfoRefresh(app,StrArrayNew);
                    case 4
                        StrArrayNew=strcat("本次频谱检测中患者信息处于 4 级，即心脏情况：基本正常！");
                        ResultInfoRefresh(app,StrArrayNew);
                    case 5
                        StrArrayNew=strcat("本次频谱检测中患者信息处于 5 级，即心脏情况：基本正常！");
                        ResultInfoRefresh(app,StrArrayNew);
                    case 6
                        StrArrayNew=strcat("本次频谱检测中患者信息处于 6 级，即心脏情况：可疑！需要进行更仔细地检测！");
                        ResultInfoRefresh(app,StrArrayNew);
                    case 7
                        StrArrayNew=strcat("本次频谱检测中患者信息处于 7 级，即心脏情况：异常！");
                        ResultInfoRefresh(app,StrArrayNew);
                    case 8
                        StrArrayNew=strcat("本次频谱检测中患者信息处于 8 级，即心脏情况：异常！");
                        ResultInfoRefresh(app,StrArrayNew);
                    case 9
                        StrArrayNew=strcat("本次频谱检测中患者信息处于 9 级，即心脏情况：严重！");
                        ResultInfoRefresh(app,StrArrayNew);
                    case 10
                        StrArrayNew=strcat("本次频谱检测中患者信息处于 10 级，即心脏情况：严重！");
                        ResultInfoRefresh(app,StrArrayNew);
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.FrequencyDomainECGAnalyzerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {688, 688, 688};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {688, 688};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {312, '1x', 279};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FrequencyDomainECGAnalyzerUIFigure = uifigure('Visible', 'off');
            app.FrequencyDomainECGAnalyzerUIFigure.Name = 'Frequency Domain ECG Analyzer';
            app.FrequencyDomainECGAnalyzerUIFigure.CloseRequestFcn = createCallbackFcn(app, @FrequencyDomainECGAnalyzerUIFigureCloseRequest, true);
            app.FrequencyDomainECGAnalyzerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.FrequencyDomainECGAnalyzerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.Panel = uipanel(app.GridLayout4);
            app.Panel.Title = '操作界面';
            app.GridLayout5 = uigridlayout(app.Panel);
            app.Panel_2 = uipanel(app.GridLayout5);
            app.Panel_2.Title = '原始导联数据导入与预处理';
            app.GridLayout6 = uigridlayout(app.Panel_2);
            app.Button_II_Import = uibutton(app.GridLayout6, 'push');
            app.Button_II_Import.ButtonPushedFcn = createCallbackFcn(app, @Button_II_ImportButtonPushed, true);
            app.Button_II_Import.Text = 'II信号';
            app.Button_V5_Import = uibutton(app.GridLayout6, 'push');
            app.Button_V5_Import.ButtonPushedFcn = createCallbackFcn(app, @Button_V5_ImportButtonPushed, true);
            app.Button_V5_Import.Text = 'V5信号';
            app.Button_DataPreprocessing = uibutton(app.GridLayout6, 'push');
            app.Button_DataPreprocessing.ButtonPushedFcn = createCallbackFcn(app, @Button_DataPreprocessingPushed, true);
            app.Button_DataPreprocessing.Text = '信号预处理';
            app.Panel_3 = uipanel(app.GridLayout5);
            app.Panel_3.Title = '各项指标检测';
            app.GridLayout21 = uigridlayout(app.Panel_3);
            app.GridLayout7 = uigridlayout(app.GridLayout21);
            app.Button_AutoPower = uibutton(app.GridLayout7, 'push');
            app.Button_AutoPower.ButtonPushedFcn = createCallbackFcn(app, @Button_AutoPowerPushed, true);
            app.Button_AutoPower.Text = '1.自功率谱指标';
            app.Button_Amplitude = uibutton(app.GridLayout7, 'push');
            app.Button_Amplitude.ButtonPushedFcn = createCallbackFcn(app, @Button_AmplitudePushed, true);
            app.Button_Amplitude.Text = '2.幅频指标';
            app.Button_CF = uibutton(app.GridLayout7, 'push');
            app.Button_CF.ButtonPushedFcn = createCallbackFcn(app, @Button_CFPushed, true);
            app.Button_CF.Text = '6.相关函数指标';
            app.Button_RF = uibutton(app.GridLayout7, 'push');
            app.Button_RF.ButtonPushedFcn = createCallbackFcn(app, @Button_RFPushed, true);
            app.Button_RF.Text = '4.相干函数指标';
            app.Button_Phase = uibutton(app.GridLayout7, 'push');
            app.Button_Phase.ButtonPushedFcn = createCallbackFcn(app, @Button_PhasePushed, true);
            app.Button_Phase.Text = '3.相频指标';
            app.Button_PIH = uibutton(app.GridLayout7, 'push');
            app.Button_PIH.ButtonPushedFcn = createCallbackFcn(app, @Button_PIHPushed, true);
            app.Button_PIH.Text = '5.脉冲响应指标';
            app.NoteLabel = uilabel(app.GridLayout21);
            app.NoteLabel.Text = 'Note：请严格按照本模块顺序进行相关操作 !';
            app.Button_DelAll = uibutton(app.GridLayout21, 'push');
            app.Button_DelAll.ButtonPushedFcn = createCallbackFcn(app, @Button_DelAllPushed, true);
            app.Button_DelAll.Text = '清空本次导入的数据及其相关操作';
            app.Panel_4 = uipanel(app.GridLayout5);
            app.Panel_4.Title = '操作日志';
            app.GridLayout8 = uigridlayout(app.Panel_4);
            app.Button_ClearLog = uibutton(app.GridLayout8, 'push');
            app.Button_ClearLog.ButtonPushedFcn = createCallbackFcn(app, @Button_ClearLogPushed, true);
            app.Button_ClearLog.Text = '清空日志';
            app.TextArea_Log = uitextarea(app.GridLayout8);
            app.TextArea_Log.Editable = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.Panel_6 = uipanel(app.GridLayout2);
            app.Panel_6.Title = '数据监测界面';
            app.GridLayout3 = uigridlayout(app.Panel_6);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '信号波形';
            app.GridLayout9 = uigridlayout(app.Tab);
            app.GridLayout18 = uigridlayout(app.GridLayout9);
            app.UIAxes_IIRawPic = uiaxes(app.GridLayout18);
            title(app.UIAxes_IIRawPic, 'II Raw Signals')
            xlabel(app.UIAxes_IIRawPic, 'Sampling Points')
            ylabel(app.UIAxes_IIRawPic, 'Voltage (mV)')
            app.UIAxes_V5RawPic = uiaxes(app.GridLayout18);
            title(app.UIAxes_V5RawPic, 'V5 Raw Signals')
            xlabel(app.UIAxes_V5RawPic, 'Sampling Points')
            ylabel(app.UIAxes_V5RawPic, 'Voltage (mV)')
            app.TextArea_RawSigInfo = uitextarea(app.GridLayout9);
            app.TextArea_RawSigInfo.Editable = 'off';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '自功率谱指标';
            app.GridLayout10 = uigridlayout(app.Tab_2);
            app.UIAxes_AutoPower = uiaxes(app.GridLayout10);
            title(app.UIAxes_AutoPower, 'Auto Power Spectrum')
            xlabel(app.UIAxes_AutoPower, 'Frenquency (Hz)')
            ylabel(app.UIAxes_AutoPower, 'Power (pW)')
            zlabel(app.UIAxes_AutoPower, 'Z')
            app.Panel_7 = uipanel(app.GridLayout10);
            app.Panel_7.Title = '自功率谱相关临床指标监测';
            app.GridLayout11 = uigridlayout(app.Panel_7);
            app.Lamp_1 = uilamp(app.GridLayout11);
            app.Lamp_2 = uilamp(app.GridLayout11);
            app.Lamp_3 = uilamp(app.GridLayout11);
            app.Lamp_4 = uilamp(app.GridLayout11);
            app.Lamp_5 = uilamp(app.GridLayout11);
            app.Lamp_6 = uilamp(app.GridLayout11);
            app.Lamp_7 = uilamp(app.GridLayout11);
            app.Lamp_8 = uilamp(app.GridLayout11);
            app.V5Label = uilabel(app.GridLayout11);
            app.V5Label.Text = 'V5 导联';
            app.IILabel = uilabel(app.GridLayout11);
            app.IILabel.Text = ' II 导联';
            app.Label = uilabel(app.GridLayout11);
            app.Label.Text = '1/2 指标';
            app.NLabel = uilabel(app.GridLayout11);
            app.NLabel.Text = '1-N 指标';
            app.Label_2 = uilabel(app.GridLayout11);
            app.Label_2.Text = '5/10 指标';
            app.TULabel = uilabel(app.GridLayout11);
            app.TULabel.Text = 'TU 指标';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '幅频指标';
            app.GridLayout14 = uigridlayout(app.Tab_4);
            app.UIAxes_AmpPic = uiaxes(app.GridLayout14);
            title(app.UIAxes_AmpPic, 'Amplitude-Frequency Diagram')
            xlabel(app.UIAxes_AmpPic, 'Frequency (Hz)')
            ylabel(app.UIAxes_AmpPic, 'Amplitude')
            zlabel(app.UIAxes_AmpPic, 'Z')
            app.TextArea_AmpInfo = uitextarea(app.GridLayout14);
            app.TextArea_AmpInfo.Editable = 'off';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '相频指标';
            app.GridLayout12 = uigridlayout(app.Tab_3);
            app.Panel_8 = uipanel(app.GridLayout12);
            app.Panel_8.Title = '相频函数相关临床指标监测';
            app.GridLayout13 = uigridlayout(app.Panel_8);
            app.Lamp_9 = uilamp(app.GridLayout13);
            app.Lamp_10 = uilamp(app.GridLayout13);
            app.DLabel = uilabel(app.GridLayout13);
            app.DLabel.Text = 'D (相移超限)';
            app.WLabel = uilabel(app.GridLayout13);
            app.WLabel.Text = 'W (波折过多)';
            app.GridLayout23 = uigridlayout(app.GridLayout12);
            app.UIAxes_PhasePic = uiaxes(app.GridLayout23);
            title(app.UIAxes_PhasePic, 'Phase Diagram')
            xlabel(app.UIAxes_PhasePic, 'Frequency (Hz)')
            ylabel(app.UIAxes_PhasePic, 'Deg (°)')
            zlabel(app.UIAxes_PhasePic, 'Z')
            app.UIAxes_MTT = uiaxes(app.GridLayout23);
            title(app.UIAxes_MTT, 'MTT')
            zlabel(app.UIAxes_MTT, 'Z')
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '相干函数指标';
            app.GridLayout16 = uigridlayout(app.Tab_5);
            app.UIAxes_RFPic = uiaxes(app.GridLayout16);
            title(app.UIAxes_RFPic, 'Related Function Diagram')
            xlabel(app.UIAxes_RFPic, 'Frequency (Hz)')
            zlabel(app.UIAxes_RFPic, 'Z')
            app.Panel_16 = uipanel(app.GridLayout16);
            app.Panel_16.Title = '相干函数相关临床指标监测';
            app.GridLayout13_3 = uigridlayout(app.Panel_16);
            app.Lamp_11 = uilamp(app.GridLayout13_3);
            app.Lamp_12 = uilamp(app.GridLayout13_3);
            app.Lamp_13 = uilamp(app.GridLayout13_3);
            app.CpLabel = uilabel(app.GridLayout13_3);
            app.CpLabel.Text = 'Cp';
            app.CTLabel = uilabel(app.GridLayout13_3);
            app.CTLabel.Text = 'CT';
            app.CbLLabel = uilabel(app.GridLayout13_3);
            app.CbLLabel.Text = 'CbL';
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '脉冲响应指标';
            app.GridLayout15 = uigridlayout(app.Tab_7);
            app.UIAxes_PIHPic = uiaxes(app.GridLayout15);
            title(app.UIAxes_PIHPic, 'PIH Diagram')
            zlabel(app.UIAxes_PIHPic, 'Z')
            app.Panel_17 = uipanel(app.GridLayout15);
            app.Panel_17.Title = '脉冲响应函数相关临床指标监测';
            app.GridLayout13_4 = uigridlayout(app.Panel_17);
            app.Lamp_14 = uilamp(app.GridLayout13_4);
            app.Lamp_15 = uilamp(app.GridLayout13_4);
            app.PVLabel = uilabel(app.GridLayout13_4);
            app.PVLabel.Text = 'PV';
            app.MLabel = uilabel(app.GridLayout13_4);
            app.MLabel.Text = 'M';
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '互相关指标';
            app.GridLayout17 = uigridlayout(app.Tab_6);
            app.UIAxes_CFPic = uiaxes(app.GridLayout17);
            title(app.UIAxes_CFPic, 'Cross-correlation Function Diagram')
            zlabel(app.UIAxes_CFPic, 'Z')
            app.Panel_15 = uipanel(app.GridLayout17);
            app.Panel_15.Title = '互相关函数相关临床指标监测';
            app.GridLayout13_2 = uigridlayout(app.Panel_15);
            app.Lamp_16 = uilamp(app.GridLayout13_2);
            app.Lamp_17 = uilamp(app.GridLayout13_2);
            app.Lamp_18 = uilamp(app.GridLayout13_2);
            app.RVLabel = uilabel(app.GridLayout13_2);
            app.RVLabel.Text = 'RV';
            app.RDLabel = uilabel(app.GridLayout13_2);
            app.RDLabel.Text = 'RD';
            app.NWLabel = uilabel(app.GridLayout13_2);
            app.NWLabel.Text = 'NW';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout19 = uigridlayout(app.RightPanel);
            app.Panel_10 = uipanel(app.GridLayout19);
            app.Panel_10.Title = '评分与诊断界面';
            app.GridLayout20 = uigridlayout(app.Panel_10);
            app.Panel_11 = uipanel(app.GridLayout20);
            app.Panel_11.Title = '各项指标得分表';
            app.GridLayout22 = uigridlayout(app.Panel_11);
            app.UITable = uitable(app.GridLayout22);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Panel_12 = uipanel(app.GridLayout20);
            app.Panel_12.Title = '结果初步诊断';
            app.GridLayout24 = uigridlayout(app.Panel_12);
            app.TextArea_Result = uitextarea(app.GridLayout24);
            app.TextArea_Result.Editable = 'off';
            app.Button_result = uibutton(app.GridLayout24, 'push');
            app.Button_result.ButtonPushedFcn = createCallbackFcn(app, @Button_resultPushed, true);
            app.Button_result.Text = '显示诊断结果';
            % Show the figure after all components are created
            app.FrequencyDomainECGAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FrequencyDomainECGAnalyzerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FrequencyDomainECGAnalyzerUIFigure)
        end
    end
end