classdef FSAC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        HelpMenu                    matlab.ui.container.Menu
        DocumentMenu                matlab.ui.container.Menu
        VersionMenu                 matlab.ui.container.Menu
        GridLayout_UIFigure         matlab.ui.container.GridLayout
        PanelName                   matlab.ui.control.Label
        TotalTabGroup               matlab.ui.container.TabGroup
        MainTab                     matlab.ui.container.Tab
        GridLayout_MainTab          matlab.ui.container.GridLayout
        ProcessLogPanel             matlab.ui.container.Panel
        GridLayout_ProcessLogPanel  matlab.ui.container.GridLayout
        LogPanel                    matlab.ui.container.Panel
        GridLayout_LogPanel         matlab.ui.container.GridLayout
        LogTextPanel                matlab.ui.container.Panel
        LogTextArea                 matlab.ui.control.TextArea
        ClearLogButton              matlab.ui.control.Button
        ProcessPanel                matlab.ui.container.Panel
        GridLayout_ProcessPanel     matlab.ui.container.GridLayout
        DataPanel                   matlab.ui.container.Panel
        GridLayout_DataPanel        matlab.ui.container.GridLayout
        UITable_Data                matlab.ui.control.Table
        SpectrumPanel               matlab.ui.container.Panel
        GridLayout_SpectrumPanel    matlab.ui.container.GridLayout
        PSDPanel                    matlab.ui.container.Panel
        GridLayout_PSDPanel         matlab.ui.container.GridLayout
        PSDTips                     matlab.ui.control.Label
        PfminPanel                  matlab.ui.container.Panel
        PfminSpinner                matlab.ui.control.Spinner
        PfminSpinnerLabel           matlab.ui.control.Label
        PfmaxPanel                  matlab.ui.container.Panel
        PfmaxSpinner                matlab.ui.control.Spinner
        PmaxLabel                   matlab.ui.control.Label
        CalPowerButton              matlab.ui.control.Button
        FreqAmpPanel                matlab.ui.container.Panel
        GridLayout_FreqAmpPanel     matlab.ui.container.GridLayout
        TwoSidesFigButton           matlab.ui.control.Button
        FsPanel                     matlab.ui.container.Panel
        FsSpinner                   matlab.ui.control.Spinner
        FsSpinnerLabel              matlab.ui.control.Label
        SingleSideFigButton         matlab.ui.control.Button
        LoadDataPanel               matlab.ui.container.Panel
        LoadDataButton              matlab.ui.control.Button
        ViewSigButton               matlab.ui.control.Button
        ChooseDataButton            matlab.ui.control.Button
        FilePathText                matlab.ui.control.EditField
        Label_2                     matlab.ui.control.Label
        RawSigTab                   matlab.ui.container.Tab
        GridLayout_RawSigTab        matlab.ui.container.GridLayout
        RawSigFigInfoPanel          matlab.ui.container.Panel
        RawSigFigInfoArea           matlab.ui.control.TextArea
        RawSigFigPanel              matlab.ui.container.Panel
        UIAxes_RawSigFig            matlab.ui.control.UIAxes
        FreqAmpTab                  matlab.ui.container.Tab
        GridLayout_FreqAmp          matlab.ui.container.GridLayout
        FreqAmpFigInfoPanel         matlab.ui.container.Panel
        FreqAmpFigInfoArea          matlab.ui.control.TextArea
        FreqAmpFigPanel             matlab.ui.container.Panel
        UIAxes_FreqAmpFig           matlab.ui.control.UIAxes
        PSDTab                      matlab.ui.container.Tab
        GridLayout_PSDTab           matlab.ui.container.GridLayout
        PSDFigInfoPanel             matlab.ui.container.Panel
        PSDFigInfoArea              matlab.ui.control.TextArea
        PSDFigPanel                 matlab.ui.container.Panel
        UIAxes_PSDFig               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        StrArray         % log
        RawSigFigInfo    % 原始信号图像相关信息
        FreqAmpFigInfo   % 频谱图像相关信息
        PSDFigInfo       % 功率谱图像相关信息
        fullFileName     % 选取的文件名
        FileName         % 选取的文件名
        rawSig           % 原始特征信号
        L                % 原始特征信号长度
        Fs               % 采样率
        Pfmax            % 选取的最大归一化频率值
        Pfmin            % 选取的最小归一化S频率值
    end
    
    methods (Access = private)
        function logRefresh(app,StrArrayNew)
            % 刷新日志
            if length(app.StrArray)>=5
                app.StrArray={};
            end
            app.StrArray=[app.StrArray,StrArrayNew];
            app.LogTextArea.Value=app.StrArray;
        end

        function RawSigInfoRefresh(app,RawSigFigInfoNew)
            % 刷新原始信号信息
            if length(app.RawSigFigInfo)>=5
                app.RawSigFigInfo={};
            end
            % 先清空
            app.RawSigFigInfo='';
            app.RawSigFigInfoArea.Value=app.RawSigFigInfo;
            % 再刷新
            app.RawSigFigInfo=[app.RawSigFigInfo,RawSigFigInfoNew];
            app.RawSigFigInfoArea.Value=app.RawSigFigInfo;
        end

        function FreqAmpInfoRefresh(app,FreqAmpFigInfoNew)
            % 刷新频谱图信息
            if length(app.FreqAmpFigInfo)>=5
                app.FreqAmpFigInfo={};
            end
            % 先清空
            app.FreqAmpFigInfo='';
            app.FreqAmpFigInfoArea.Value=app.FreqAmpFigInfo;
            % 再刷新
            app.FreqAmpFigInfo=[app.FreqAmpFigInfo,FreqAmpFigInfoNew];
            app.FreqAmpFigInfoArea.Value=app.FreqAmpFigInfo;
        end

        function PSDInfoRefresh(app,PSDFigInfoNew)
            % 刷新功率谱图信息
            if length(app.PSDFigInfo)>=5
                app.PSDFigInfo={};
            end
            % 先清空
            app.PSDFigInfo='';
            app.PSDFigInfoArea.Value=app.PSDFigInfo;
            % 再刷新
            app.PSDFigInfo=[app.PSDFigInfo,PSDFigInfoNew];
            app.PSDFigInfoArea.Value=app.PSDFigInfo;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 再次确定关闭
            msg1="\fontsize{12} \bf是否要关闭本窗口？";
            msg2="\fontsize{12} \bf关闭前请保存数据！";
            % newline命令用于换行
            msg=msg1+newline+msg2;
            % 窗口名称
            title='关闭窗口';
            % 窗口界面设置
            selection = uiconfirm(app.UIFigure,msg,title, ...
                        'Options',{'关闭','取消'}, ...  % 窗口选项
                        'DefaultOption',2, ...          % 默认选项
                        'CancelOption',2, ...           % 取消选项
                        'Icon','warning', ...           % 图标选择
    		            'Interpreter','tex');           % 文字控制
            % 窗口对应操作选项
            switch selection
                case '关闭'
                    delete(app);
                case '取消'
                    return;
            end
        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed(app, event)
            % 指定弹窗内容格式
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            
            % 清除日志区内容
            app.LogTextArea.Value=[''];
            % 创建弹窗提示
            msgbox('\fontsize{12} 操作日志已清理！','Tip',CreateStruct);
        end

        % Menu selected function: VersionMenu
        function VersionMenuSelected(app, event)
             %1 指定句柄
             f=app.UIFigure;
             %2 指定内容
             message=sprintf(['特征信号谱分析计算系统\n'  ...
                 '\n' ...
                 'Version: 1.0\n' ...
                 'Date: 2022.10\n'...
                 'Author: XiangLin Tan、MuQing Deng\n'...
                 'Email: 3119001607@mail2.gdut.edu.cn\n' ...
                 'Copyright @ 广东工业大学\n'...
                 '\n' ...
                 '欢迎使用本系统！\n' ...
                 ]);
             %3 弹窗设置
             uialert(f,message,'软件信息','Icon','');
        end

        % Button pushed function: ChooseDataButton
        function ChooseDataButtonPushed(app, event)
            % 指定弹窗内容格式
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            
            if isempty(app.FilePathText.Value)
                try 
                    %浏览文件并选择信号文件
                    [fileName,pathName]=uigetfile({'*.txt';'*.m';'*.slx';'*.mat';'*.*'});
                    % 文件名读取
                    app.fullFileName=fullfile(pathName,fileName); 
                    app.FilePathText.Value=app.fullFileName;
                    % 刷新日志
                    StrArrayNew={strcat('Msg：', fileName(1:end-4), '  特征信号数据成功识别！')};
                    logRefresh(app,StrArrayNew); 
                catch
                    % 抛出错误提示
                    StrArrayNew={'Err：信号文件识别错误！'};
                    logRefresh(app,StrArrayNew); 
                    msgbox('\fontsize{12} 信号文件识别错误！','Error','error',CreateStruct);
                end
            else
                try 
                    % 文件判断是否存在
                    if exist(app.FilePathText.Value)
                        app.FileName=app.FilePathText.Value;
                        StrArrayNew={strcat('Msg：', app.FileName, '  特征信号数据成功识别！')};
                        logRefresh(app,StrArrayNew);
                    else
                        % 抛出错误提示
                        StrArrayNew={'Err：文件不存在！'};
                        logRefresh(app,StrArrayNew); 
                        msgbox('\fontsize{12} 文件不存在！','Error','error',CreateStruct);
                    end
                catch
                    % 抛出错误提示
                    StrArrayNew={'Err:  信号文件识别错误！'};
                    logRefresh(app,StrArrayNew); 
                    msgbox('\fontsize{12} 信号文件识别错误！','Error','error',CreateStruct);
                end
            end       
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % 指定弹窗内容格式
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            
            try
                % 先加载用按钮选取的文件
                if isempty(app.fullFileName)
                    app.rawSig=load(app.FileName);
                    % 获取信号长度
                    app.L=length(app.rawSig);
                    StrArrayNew={strcat('Msg：',app.FileName,'  特征信号文件数据加载成功！')};
                    logRefresh(app,StrArrayNew); 
                else % 加载没用按钮，而是自己输入的文件
                    app.rawSig=load(app.fullFileName);
                    % 获取信号长度
                    app.L=length(app.rawSig);
                    StrArrayNew={strcat('Msg：',app.fullFileName,'  特征信号文件数据加载成功！')};
                    logRefresh(app,StrArrayNew); 
                end
            catch 
                % 抛出错误提示
                StrArrayNew={'Err：信号文件加载错误！'};
                logRefresh(app,StrArrayNew);
                msgbox('\fontsize{12} 信号文件加载错误！','Error','error',CreateStruct);
            end
        end

        % Button pushed function: ViewSigButton
        function ViewSigButtonPushed(app, event)
            % 指定弹窗内容格式
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            
            if isempty(app.rawSig)
                % 提示导入数据
                StrArrayNew={'Err：特征信号数据未导入！'};
                logRefresh(app,StrArrayNew);
                msgbox('\fontsize{12} 请导入特征信号数据！','Tip','warn',CreateStruct);
            else
                % 将原始特征信号画到坐标区
                plot(app.rawSig,'Parent',app.UIAxes_RawSigFig);
                % 标题
                title(app.UIAxes_RawSigFig,'原始特征信号波形图');
                % 获取信号长度
                app.L=length(app.rawSig);
                % x轴范围与名称
                app.UIAxes_RawSigFig.XLim=[0 app.L];
                app.UIAxes_RawSigFig.XLabel.String='Sampling Point';
                % y轴范围与名称
                app.UIAxes_RawSigFig.YLabel.String='Value';
                
                % 将图片信息输入相应区域
                RawSigFigInfoNew={['Msg：本信号共有 ', num2str(app.L), ' 个样本点，' ...
                    '其中最大值为 ', num2str(max(app.rawSig)), ...
                    '，最小值为 ', num2str(min(app.rawSig))]};
                RawSigInfoRefresh(app,RawSigFigInfoNew);
                
                % 数据表
                try
                    % 加载数据到数据表面板
                    app.UITable_Data.Data=app.rawSig';
                    % 设置数据表的列名
                    app.UITable_Data.ColumnName='Sampling Point';
                    % 设置数据可排序
                    set(app.UITable_Data,'ColumnEditable',true,'ColumnSortable',true);
                    % 表格内容居中
                    s=uistyle('HorizontalAlignment','center');
                    addStyle(app.UITable_Data,s,'table','');
                    % 输出日志
                    StrArrayNew={'Msg：特征信号数据已导入数据表！'};
                    logRefresh(app,StrArrayNew);
                catch 
                    % 抛出报错
                    StrArrayNew={'Err：特征信号数据导入数据表失败！'};
                    logRefresh(app,StrArrayNew);
                end
                
                % 输出日志与弹窗提示
                try
                    % 正常
                    StrArrayNew={'Msg：特征信号波形刻画成功！'};
                    logRefresh(app,StrArrayNew);
                    msgbox('\fontsize{12} 信号波形已刻画！','Tip','none',CreateStruct);
                catch 
                    % 抛出错误提示
                    StrArrayNew={'Err：特征信号波形刻画错误！'};
                    logRefresh(app,StrArrayNew);
                    msgbox('\fontsize{12} 信号波形刻画错误！','Error','error',CreateStruct);
                end
            end
        end

        % Button pushed function: SingleSideFigButton
        function SingleSideFigButtonPushed(app, event)
            % 指定弹窗内容格式
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            
            % 获取采样率
            app.Fs=app.FsSpinner.Value;
            % 先判断Fs是否为零，以及是否导入文件
            if (isempty(app.rawSig))
                % 提示导入数据
                StrArrayNew={'Err：特征信号数据未导入！'};
                logRefresh(app,StrArrayNew);
                msgbox('\fontsize{12} 请导入特征信号数据！','Tip','warn',CreateStruct);
            else
                if (~(app.Fs>0 && app.Fs<5000))
                    % 提示输入
                    msgbox('\fontsize{12} 请输入 (正确) 采样率！','Tip','warn',CreateStruct);
                else
                    try
                        % 对信号傅立叶变换
                        sig_fft=fft(app.rawSig);
                        % 归一化，保证幅值不失真
                        RR_Y1=abs(sig_fft/app.L);
                        % 取左半部分有实际意义的实频
                        RR_Y2=RR_Y1(1:app.L/2+1);
                        % 处理掉右边没有物理意义的虚频；
                        RR_Y2(2:end-1)=2*RR_Y2(2:end-1);
                        % 刻画单边谱频率
                        f=app.Fs*(0:(app.L/2))/app.L;

                        % 将频谱图画到坐标区
                        plot(f,RR_Y2,'Parent',app.UIAxes_FreqAmpFig);
                        % 标题
                        title(app.UIAxes_FreqAmpFig,'特征信号单边频谱图');
                        % x轴范围与名称
                        app.UIAxes_FreqAmpFig.XLim=[0 max(f)];
                        app.UIAxes_FreqAmpFig.XLabel.String='Frequency (Hz)';
                        % y轴范围与名称
                        app.UIAxes_FreqAmpFig.YLabel.String='Magnitude (mV)';
                   
                        % 将图片信息输入相应区域
                        MaxFreq=f(find(RR_Y2==max(RR_Y2)));
                        MinFreq=f(find(RR_Y2==min(RR_Y2)));
                        FreqAmpFigInfoNew={['Msg：本频谱图幅值最大' ...
                            '频率为 ', num2str(MaxFreq), ' (Hz)，',...
                            '幅值最小频率为 ', num2str(MinFreq), ' (Hz)']};
                        FreqAmpInfoRefresh(app,FreqAmpFigInfoNew);
                        
                        % 正常提示
                        StrArrayNew={'Msg：单边频谱信息刻画成功！'};
                        logRefresh(app,StrArrayNew);
                        msgbox('\fontsize{12} 单边频谱信息刻画成功！','Tip','none',CreateStruct);

                        % 加载数据到数据表面板
                        tempData=zeros(length(f),2);
                        tempData(:,1)=f';
                        tempData(:,2)=RR_Y2';
                        app.UITable_Data.Data=tempData;
                        % 设置数据表的列名
                        colunmNames={['Frequency'],['Magnitude']};
                        app.UITable_Data.ColumnName=colunmNames;
                        % 设置数据可排序
                        set(app.UITable_Data,'ColumnEditable',true,'ColumnSortable',true);
                        % 表格内容居中
                        s=uistyle('HorizontalAlignment','center');
                        addStyle(app.UITable_Data,s,'table','');
                        % 输出日志
                        StrArrayNew={'Msg：单边频谱信息已导入数据表！'};
                        logRefresh(app,StrArrayNew);
                    catch 
                        % 提示报错
                        StrArrayNew={'Err：单边频谱信息刻画失败！'};
                        logRefresh(app,StrArrayNew);
                        msgbox('\fontsize{12} 单边频谱信息刻画失败！','Error','error',CreateStruct);
                    end                  
                end
            end
        end

        % Button pushed function: TwoSidesFigButton
        function TwoSidesFigButtonPushed(app, event)
            % 指定弹窗内容格式
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            
            % 获取采样率
            app.Fs=app.FsSpinner.Value;
            % 先判断Fs是否为零，以及是否导入文件
            if (isempty(app.rawSig))
                % 提示导入数据
                StrArrayNew={'Err：特征信号数据未导入！'};
                logRefresh(app,StrArrayNew);
                msgbox('\fontsize{12} 请导入特征信号数据！','Tip','warn',CreateStruct);
            else
                if (~(app.Fs>0 && app.Fs<5000))
                    % 提示输入
                    msgbox('\fontsize{12} 请输入 (正确) 采样率！','Tip','warn',CreateStruct);
                else
                    try
                        % 对信号傅立叶变换
                        sig_fft=fft(app.rawSig);
                        % 归一化，保证幅值不失真
                        RR_Y1=abs(sig_fft/app.L);
                        % 刻画双边谱频率
                        f=app.Fs*(0:(app.L-1))/(app.L-1);

                        % 将频谱图画到坐标区
                        plot(f,RR_Y1,'Parent',app.UIAxes_FreqAmpFig);
                        % 标题
                        title(app.UIAxes_FreqAmpFig,'特征信号双边频谱图');
                        % x轴范围与名称
                        app.UIAxes_FreqAmpFig.XLim=[0 max(f)];
                        app.UIAxes_FreqAmpFig.XLabel.String='Frequency (Hz)';
                        % y轴范围与名称
                        app.UIAxes_FreqAmpFig.YLabel.String='Magnitude (mV)';
                   
                        % 将图片信息输入相应区域
                        MaxFreqSmall=f(min(find(RR_Y1==max(RR_Y1))));
                        MaxFreqBig=f(max(find(RR_Y1==max(RR_Y1))));
                        FreqAmpFigInfoNew={['Msg：本频谱图幅值最大' ...
                            '频率为 ', num2str(MaxFreqSmall), ' (Hz)、', ...
                            num2str(MaxFreqBig), ' (Hz)']};
                        FreqAmpInfoRefresh(app,FreqAmpFigInfoNew);
                        
                        % 正常提示
                        StrArrayNew={'Msg：双边频谱信息刻画成功！'};
                        logRefresh(app,StrArrayNew);
                        msgbox('\fontsize{12} 双边频谱信息刻画成功！','Tip','none',CreateStruct);

                        % 加载数据到数据表面板
                        tempData=zeros(length(f),2);
                        tempData(:,1)=f';
                        tempData(:,2)=RR_Y1';
                        app.UITable_Data.Data=tempData;
                        % 设置数据表的列名
                        colunmNames={['Frequency'],['Magnitude']};
                        app.UITable_Data.ColumnName=colunmNames;
                        % 设置数据可排序
                        set(app.UITable_Data,'ColumnEditable',true,'ColumnSortable',true);
                        % 表格内容居中
                        s=uistyle('HorizontalAlignment','center');
                        addStyle(app.UITable_Data,s,'table','');
                        % 输出日志
                        StrArrayNew={'Msg：双边频谱信息已导入数据表！'};
                        logRefresh(app,StrArrayNew);
                    catch 
                        % 提示报错
                        StrArrayNew={'Err：双边频谱信息刻画失败！'};
                        logRefresh(app,StrArrayNew);
                        msgbox('\fontsize{12} 双边频谱信息刻画失败！','Error','error',CreateStruct);
                    end                  
                end
            end
        end

        % Button pushed function: CalPowerButton
        function CalPowerButtonPushed(app, event)
            % 指定弹窗内容格式
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            
            % 获取采样率
            app.Fs=app.FsSpinner.Value;

            % 先判断Fs、Pfmax、Pfmin的值是否符合要求，以及是否导入文件
            if (isempty(app.rawSig))
                % 提示导入数据
                StrArrayNew={'Err：特征信号数据未导入！'};
                logRefresh(app,StrArrayNew);
                msgbox('\fontsize{12} 请导入特征信号数据！','Tip','warn',CreateStruct);
            else
                if (~(app.Fs>0 && app.Fs<5000))
                    % 提示输入
                    msgbox('\fontsize{12} 请保持正确的采样率！','Tip','warn',CreateStruct);
                else
                    if ((app.PfminSpinner.Value>=0 && app.PfminSpinner.Value<=1) ...
                         && (app.PfmaxSpinner.Value>=0 && app.PfmaxSpinner.Value<=1))
                        if (app.PfmaxSpinner.Value>=app.PfminSpinner.Value)
                            % 获取 Pfmax、Pfmin
                            app.Pfmax=app.PfmaxSpinner.Value;
                            app.Pfmin=app.PfminSpinner.Value;
                            
                            % 加窗
                            window=boxcar(app.L);
                            % 采样点数
                            nfft=app.L;
                            % 功率谱
                            [Psd,freq]=periodogram(app.rawSig,window,nfft,app.Fs);
                            % 将频谱图画到坐标区
                            Freq=freq/app.Fs;
                            plot(Freq,Psd,'Parent',app.UIAxes_PSDFig);
                            % 标题
                            title(app.UIAxes_PSDFig,'特征信号归一化频率功率谱图');
                            % x轴范围与名称
                            app.UIAxes_PSDFig.XLabel.String='Normalized Frequency (rad)';
                            % y轴范围与名称
                            app.UIAxes_PSDFig.YLabel.String='Power/Normalized Frequency ((mV)^2/rad)';
    
                            % 选取下标
                            Xinterval=find(Freq>app.Pfmin & Freq<app.Pfmax);
                            % 求积分值
                            intOfPower=trapz(Freq(Xinterval), Psd(Xinterval));
    
                            % 将图片信息输入相应区域
                            PSDFigInfoNew={strcat('Msg：你选取的频率范围在', ...
                                num2str(app.Pfmin),' ~ ', num2str(app.Pfmax), ...
                                '区域内的功率的值为 ', num2str(intOfPower), ...
                                ' (mV)^2')};  
                            PSDInfoRefresh(app,PSDFigInfoNew);
    
                            % 加载数据到数据表面板
                            tempData=linspace(1,3,3);
                            tempData(1,1)=app.Pfmin;
                            tempData(1,2)=app.Pfmax;
                            tempData(1,3)=intOfPower;
                            app.UITable_Data.Data=tempData;
                            % 设置数据表的列名
                            colunmNames={['选取的左端点'],['选取的右端点'],['功率值']};
                            app.UITable_Data.ColumnName=colunmNames;
                            % 设置数据可排序
                            set(app.UITable_Data,'ColumnEditable',true,'ColumnSortable',true);
                            % 表格内容居中
                            s=uistyle('HorizontalAlignment','center');
                            addStyle(app.UITable_Data,s,'table','');
                            % 输出日志
                            StrArrayNew={'Msg：功率谱计算信息已导入数据表！'};
                            logRefresh(app,StrArrayNew);
                        else
                            % 提示导入数据
                            StrArrayNew={'Err：输入的 Pfmax 小于 Pfmin ！'};
                            logRefresh(app,StrArrayNew);
                            msgbox('\fontsize{12} 输入的 Pfmax 请大于 Pfmin！','Tip','warn',CreateStruct);
                        end
                    else
                        % 提示导入数据
                        StrArrayNew={'Err：输入的 Pfmax 或 Pfmin 值有误！'};
                        logRefresh(app,StrArrayNew);
                        msgbox('\fontsize{12} 请输入正确的 Pfmax 与 Pfmin 值！','Tip','warn',CreateStruct);
                    end
                end
            end
        end

        % Menu selected function: DocumentMenu
        function DocumentMenuSelected(app, event)
            % 再次确定是否打开帮助文档地址
            msg1="\fontsize{12} \bf是否前往查看帮助文档？";
            msg2="\fontsize{12} \bf请提前将网络连接开启！";
            % newline命令用于换行
            msg=msg1+newline+msg2;
            % 窗口名称
            title='帮助文档';
            % 窗口界面设置
            selection = uiconfirm(app.UIFigure,msg,title, ...
                        'Options',{'前往','取消'}, ...  % 窗口选项
                        'DefaultOption',1, ...          % 默认选项
                        'CancelOption',2, ...           % 取消选项
                        'Icon','', ...                  % 图标选择
    		            'Interpreter','tex');           % 文字控制
            % 窗口对应操作选项
            switch selection
                case '前往'
                    web('https://blog.csdn.net/Stay_with_you/article/details/127436936?spm=1001.2014.3001.5501');
                case '取消'
                    return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '特征信号谱分析计算系统';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = '帮助 (Help)';
            app.DocumentMenu = uimenu(app.HelpMenu);
            app.DocumentMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentMenuSelected, true);
            app.DocumentMenu.Checked = 'on';
            app.DocumentMenu.Text = '帮助文档 (Document)';
            app.VersionMenu = uimenu(app.HelpMenu);
            app.VersionMenu.MenuSelectedFcn = createCallbackFcn(app, @VersionMenuSelected, true);
            app.VersionMenu.Checked = 'on';
            app.VersionMenu.Text = '软件信息 (Version)';
            app.GridLayout_UIFigure = uigridlayout(app.UIFigure);
            app.TotalTabGroup = uitabgroup(app.GridLayout_UIFigure);
            app.MainTab = uitab(app.TotalTabGroup);
            app.MainTab.Title = '主界面';
            app.GridLayout_MainTab = uigridlayout(app.MainTab);
            app.LoadDataPanel = uipanel(app.GridLayout_MainTab);
            app.LoadDataPanel.Title = '导入并加载特征信号';
            app.Label_2 = uilabel(app.LoadDataPanel);
            app.Label_2.Text = '特征信号文件：';
            app.FilePathText = uieditfield(app.LoadDataPanel, 'text');
            app.ChooseDataButton = uibutton(app.LoadDataPanel, 'push');
            app.ChooseDataButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDataButtonPushed, true);
            app.ChooseDataButton.Text = '选择数据';
            app.ViewSigButton = uibutton(app.LoadDataPanel, 'push');
            app.ViewSigButton.ButtonPushedFcn = createCallbackFcn(app, @ViewSigButtonPushed, true);
            app.ViewSigButton.Text = '查看信号波形';
            app.LoadDataButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = '加载数据';
            app.ProcessLogPanel = uipanel(app.GridLayout_MainTab);
            app.GridLayout_ProcessLogPanel = uigridlayout(app.ProcessLogPanel);
            app.ProcessPanel = uipanel(app.GridLayout_ProcessLogPanel);
            app.ProcessPanel.Title = '分析计算';
            app.GridLayout_ProcessPanel = uigridlayout(app.ProcessPanel);
            app.SpectrumPanel = uipanel(app.GridLayout_ProcessPanel);
            app.SpectrumPanel.Title = '谱分析';
            app.GridLayout_SpectrumPanel = uigridlayout(app.SpectrumPanel);
            app.FreqAmpPanel = uipanel(app.GridLayout_SpectrumPanel);
            app.FreqAmpPanel.Title = '频谱分析';
            app.GridLayout_FreqAmpPanel = uigridlayout(app.FreqAmpPanel);
            app.SingleSideFigButton = uibutton(app.GridLayout_FreqAmpPanel, 'push');
            app.SingleSideFigButton.ButtonPushedFcn = createCallbackFcn(app, @SingleSideFigButtonPushed, true);
            app.SingleSideFigButton.Text = '单边幅频谱';
            app.FsPanel = uipanel(app.GridLayout_FreqAmpPanel);
            app.FsSpinnerLabel = uilabel(app.FsPanel);
            app.FsSpinnerLabel.Text = '采样率(Fs)：';
            app.FsSpinner = uispinner(app.FsPanel);
            app.TwoSidesFigButton = uibutton(app.GridLayout_FreqAmpPanel, 'push');
            app.TwoSidesFigButton.ButtonPushedFcn = createCallbackFcn(app, @TwoSidesFigButtonPushed, true);
            app.TwoSidesFigButton.Text = '双边幅频谱';
            app.PSDPanel = uipanel(app.GridLayout_SpectrumPanel);
            app.PSDPanel.Title = '功率谱分析';
            app.GridLayout_PSDPanel = uigridlayout(app.PSDPanel);
            app.CalPowerButton = uibutton(app.GridLayout_PSDPanel, 'push');
            app.CalPowerButton.ButtonPushedFcn = createCallbackFcn(app, @CalPowerButtonPushed, true);
            app.CalPowerButton.Text = '计算功率';
            app.PfmaxPanel = uipanel(app.GridLayout_PSDPanel);
            app.PmaxLabel = uilabel(app.PfmaxPanel);
            app.PmaxLabel.Text = 'Pfmax：';
            app.PfmaxSpinner = uispinner(app.PfmaxPanel);
            app.PfminPanel = uipanel(app.GridLayout_PSDPanel);
            app.PfminSpinnerLabel = uilabel(app.PfminPanel);
            app.PfminSpinnerLabel.Text = 'Pfmin：';
            app.PfminSpinner = uispinner(app.PfminPanel);
            app.PSDTips = uilabel(app.GridLayout_PSDPanel);
            app.PSDTips.Text = 'Pfmax 与 Pfmin 均在 [0,1] 之间';
            app.DataPanel = uipanel(app.GridLayout_ProcessPanel);
            app.DataPanel.Title = '数据表';
            app.GridLayout_DataPanel = uigridlayout(app.DataPanel);
            app.UITable_Data = uitable(app.GridLayout_DataPanel);
            app.UITable_Data.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_Data.RowName = {};
            app.LogPanel = uipanel(app.GridLayout_ProcessLogPanel);
            app.LogPanel.Title = '操作日志';
            app.GridLayout_LogPanel = uigridlayout(app.LogPanel);
            app.ClearLogButton = uibutton(app.GridLayout_LogPanel, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed, true);
            app.ClearLogButton.Text = '清空日志';
            app.LogTextPanel = uipanel(app.GridLayout_LogPanel);
            app.LogTextArea = uitextarea(app.LogTextPanel);
            app.LogTextArea.Editable = 'off';
            app.RawSigTab = uitab(app.TotalTabGroup);
            app.RawSigTab.Title = '特征信号波形图';
            app.GridLayout_RawSigTab = uigridlayout(app.RawSigTab);
            app.RawSigFigPanel = uipanel(app.GridLayout_RawSigTab);
            app.UIAxes_RawSigFig = uiaxes(app.RawSigFigPanel);
            title(app.UIAxes_RawSigFig, 'Title')
            xlabel(app.UIAxes_RawSigFig, 'X')
            ylabel(app.UIAxes_RawSigFig, 'Y')
            zlabel(app.UIAxes_RawSigFig, 'Z')
            app.RawSigFigInfoPanel = uipanel(app.GridLayout_RawSigTab);
            app.RawSigFigInfoArea = uitextarea(app.RawSigFigInfoPanel);
            app.RawSigFigInfoArea.Editable = 'off';
            app.FreqAmpTab = uitab(app.TotalTabGroup);
            app.FreqAmpTab.Title = '特征信号频谱信息';
            app.GridLayout_FreqAmp = uigridlayout(app.FreqAmpTab);
            app.FreqAmpFigPanel = uipanel(app.GridLayout_FreqAmp);
            app.UIAxes_FreqAmpFig = uiaxes(app.FreqAmpFigPanel);
            title(app.UIAxes_FreqAmpFig, 'Title')
            xlabel(app.UIAxes_FreqAmpFig, 'X')
            ylabel(app.UIAxes_FreqAmpFig, 'Y')
            zlabel(app.UIAxes_FreqAmpFig, 'Z')
            app.FreqAmpFigInfoPanel = uipanel(app.GridLayout_FreqAmp);
            app.FreqAmpFigInfoArea = uitextarea(app.FreqAmpFigInfoPanel);
            app.FreqAmpFigInfoArea.Editable = 'off';
            app.PSDTab = uitab(app.TotalTabGroup);
            app.PSDTab.Title = '特征信号功率谱信息';
            app.GridLayout_PSDTab = uigridlayout(app.PSDTab);
            app.PSDFigPanel = uipanel(app.GridLayout_PSDTab);
            app.UIAxes_PSDFig = uiaxes(app.PSDFigPanel);
            title(app.UIAxes_PSDFig, 'Title')
            xlabel(app.UIAxes_PSDFig, 'X')
            ylabel(app.UIAxes_PSDFig, 'Y')
            zlabel(app.UIAxes_PSDFig, 'Z')
            app.PSDFigInfoPanel = uipanel(app.GridLayout_PSDTab);
            app.PSDFigInfoArea = uitextarea(app.PSDFigInfoPanel);
            app.PSDFigInfoArea.Editable = 'off';
            app.PanelName = uilabel(app.GridLayout_UIFigure);
            app.PanelName.Text = '特征信号谱分析计算系统';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FSAC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end